classdef PD_DEFENSE_SOFTWARE_CHINESE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PDDEFENSESOFTWARENUAAUIFigure   matlab.ui.Figure
        Image                           matlab.ui.control.Image
        PercentValue                    matlab.ui.control.NumericEditField
        PercentSign                     matlab.ui.control.EditField
        Switch                          matlab.ui.control.Switch
        Label_6                         matlab.ui.control.Label
        USFLAG                          matlab.ui.control.Image
        CHINAFLAG                       matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        AUTOSWITCHERTab                 matlab.ui.container.Tab
        ClearButtonGLRT                 matlab.ui.control.Button
        PRIODJammingEditField_2         matlab.ui.control.NumericEditField
        PRIODSpoofingEditField_2        matlab.ui.control.NumericEditField
        PRIODMultipathEditField_2       matlab.ui.control.NumericEditField
        PRIODCleanEditField_2           matlab.ui.control.NumericEditField
        INITIALLabel_2                  matlab.ui.control.Label
        PERFOMANCEANALYSISFORPDAUTOSWITCHERSPOOFINGDETECTIONLabel  matlab.ui.control.Label
        GeneratePMCDataButton_2         matlab.ui.control.Button
        GenerateMCDataButton_2          matlab.ui.control.Button
        Panel_13                        matlab.ui.container.Panel
        COMBINED                        matlab.ui.control.Button
        PMD                             matlab.ui.control.Button
        PSD                             matlab.ui.control.Button
        Panel_12                        matlab.ui.container.Panel
        Panel_14                        matlab.ui.container.Panel
        SpooferPowerAdvantLabel         matlab.ui.control.Label
        SpooferPowerAdvantage           matlab.ui.control.NumericEditField
        EarlyLateColleratorLabel_5      matlab.ui.control.Label
        EarlyLateCollerator             matlab.ui.control.NumericEditField
        SDforPowermetricLabel           matlab.ui.control.Label
        SDforPowermetric                matlab.ui.control.NumericEditField
        NominalSignalPowerLabel         matlab.ui.control.Label
        NominalSignalPower              matlab.ui.control.NumericEditField
        NoofMCRunsEpochLabel            matlab.ui.control.Label
        NoofMCRunsEpoch                 matlab.ui.control.NumericEditField
        FalseAlertProbabilityLabel      matlab.ui.control.Label
        FalseAlertProbability           matlab.ui.control.NumericEditField
        UIAxesMESHEDsmall               matlab.ui.control.UIAxes
        UIAxesMESHED                    matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        Button_7                        matlab.ui.control.Button
        PRIODJammingEditField           matlab.ui.control.NumericEditField
        PRIODSpoofingEditField          matlab.ui.control.NumericEditField
        PRIODMultipathEditField         matlab.ui.control.NumericEditField
        PRIODCleanEditField             matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PMCButton                       matlab.ui.control.Button
        MCButton                        matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        Button_2                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        TcEditFieldLabelPDMODEL         matlab.ui.control.Label
        TcEditFieldPDMODEL              matlab.ui.control.NumericEditField
        WFEEditFieldLabelPDMODEL        matlab.ui.control.Label
        WFEEditFieldPDMODEL             matlab.ui.control.NumericEditField
        TaEditFieldLabelPDMODEL         matlab.ui.control.Label
        TaEditFieldPDMODEL              matlab.ui.control.NumericEditField
        TaudEditFieldLabelPDMODEL       matlab.ui.control.Label
        TaudEditFieldPDMODEL            matlab.ui.control.NumericEditField
        MsEditFieldLabelPDMODEL         matlab.ui.control.Label
        MsEditFieldPDMODEL              matlab.ui.control.NumericEditField
        N0EditFieldLabelPDMODEL         matlab.ui.control.Label
        N0EditFieldPDMODEL              matlab.ui.control.NumericEditField
        PAEditFieldLabel                matlab.ui.control.Label
        PAEditFieldPDMODEL              matlab.ui.control.NumericEditField
        VectorsEditFieldPDMODEL         matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        TrialsEditFieldLabelPDMODEL     matlab.ui.control.Label
        PbetaEditFieldLabel             matlab.ui.control.Label
        PbetaEditFieldPDMODEL           matlab.ui.control.NumericEditField
        WFEbetaEditFieldLabel           matlab.ui.control.Label
        WFEbetaEditFieldPDMODEL         matlab.ui.control.NumericEditField
        SigmaPEditFieldLabel            matlab.ui.control.Label
        SigmaPEditFieldPDMODEL          matlab.ui.control.NumericEditField
        TrialsEditFieldPDMODEL          matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        Tab_2                           matlab.ui.container.Tab
        Panel_10                        matlab.ui.container.Panel
        Panel_5                         matlab.ui.container.Panel
        POEditFieldLabelTOPPINCER       matlab.ui.control.Label
        POEditFieldTOPPINCER            matlab.ui.control.NumericEditField
        SamplesEditFieldLabelTOPPINCER  matlab.ui.control.Label
        SamplesEditFieldTOPPINCER       matlab.ui.control.NumericEditField
        SigmaPEditFieldLabelTOPPINCER   matlab.ui.control.Label
        SigmaPEditFieldTOPPINCER        matlab.ui.control.NumericEditField
        TcEditFieldLabelTOPPINCER       matlab.ui.control.Label
        TcEditFieldTOPPINCER            matlab.ui.control.NumericEditField
        PbetaEditFieldLabelTOPPINCER    matlab.ui.control.Label
        PbetaEditFieldTOPPINCER         matlab.ui.control.NumericEditField
        WFEbetaEditFieldLabelTOPPINCER  matlab.ui.control.Label
        WFEbetaEditFieldTOPPINCER       matlab.ui.control.NumericEditField
        WFEEditFieldLabelTOPPINCER      matlab.ui.control.Label
        WFEEditFieldTOPPINCER           matlab.ui.control.NumericEditField
        TaEditFieldLabelTOPPINCER       matlab.ui.control.Label
        TaEditFieldTOPPINCER            matlab.ui.control.NumericEditField
        taudEditFieldLabelTOPPINCER     matlab.ui.control.Label
        taudEditFieldTOPPINCER          matlab.ui.control.NumericEditField
        MsEditFieldLabelTOPPINCER       matlab.ui.control.Label
        MsEditFieldTOPPINCER            matlab.ui.control.NumericEditField
        N0EditFieldLabelTOPPINCER       matlab.ui.control.Label
        N0EditFieldTOPPINCER            matlab.ui.control.NumericEditField
        PAEditFieldLabelTOPPINCER       matlab.ui.control.Label
        PAEditFieldTOPPINCER            matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Button_9                        matlab.ui.control.Button
        PP0Button                       matlab.ui.control.Button
        DButton_2                       matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        DButton                         matlab.ui.control.Button
        Panel_11                        matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        Tab_3                           matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        Panel_15                        matlab.ui.container.Panel
        FIGURES                         matlab.ui.control.Button
        INITIAL                         matlab.ui.control.Button
        USEPMCDATABRBButton             matlab.ui.control.Button
        UIAxesBRB1                      matlab.ui.control.UIAxes
        UIAxesBRB2                      matlab.ui.control.UIAxes
        PDMLTab                         matlab.ui.container.Tab
        TrialsEditFieldLabel_6          matlab.ui.control.Label
        nTapsEditFieldMPELQ             matlab.ui.control.NumericEditField
        ClearButtonMPELQ                matlab.ui.control.Button
        PRIODJammingEditFieldMPELQ      matlab.ui.control.NumericEditField
        PRIODSpoofingEditFieldMPELQ     matlab.ui.control.NumericEditField
        PRIODMultipathEditFieldMPELQ    matlab.ui.control.NumericEditField
        PRIODCleanEditFieldMPELQ        matlab.ui.control.NumericEditField
        DLabel                          matlab.ui.control.Label
        PDMLLabel                       matlab.ui.control.Label
        MPELQButton                     matlab.ui.control.Button
        Panel_8                         matlab.ui.container.Panel
        ALLSIGNALSButtonMPELQ           matlab.ui.control.Button
        JAMMINGButtonMPELQ              matlab.ui.control.Button
        SPOOFINGButtonMPELQ             matlab.ui.control.Button
        MULTIPATHButtonMPELQ            matlab.ui.control.Button
        CLEANButtonMPELQ                matlab.ui.control.Button
        Panel_7                         matlab.ui.container.Panel
        Panel_9                         matlab.ui.container.Panel
        VectorEditFieldLabelMPELQ       matlab.ui.control.Label
        TrialsEditFieldMPELQ            matlab.ui.control.NumericEditField
        VectorEditFieldMPELQ            matlab.ui.control.NumericEditField
        TrialsEditFieldLabelMPELQ       matlab.ui.control.Label
        N0EditFieldMPELQ                matlab.ui.control.NumericEditField
        NoEditFieldLabelMPELQ           matlab.ui.control.Label
        MsEditFieldMPELQ                matlab.ui.control.NumericEditField
        msEditFieldLabelMPELQ           matlab.ui.control.Label
        TrueDelayEditFieldMPELQ         matlab.ui.control.NumericEditField
        VectorsEditFieldLabelMPELQ      matlab.ui.control.Label
        ssigmaPEditFieldLabelMPELQ      matlab.ui.control.Label
        sigmaPEditFieldMPELQ            matlab.ui.control.NumericEditField
        sTcEditFieldLabelMPELQ          matlab.ui.control.Label
        TcEditFieldMPELQ                matlab.ui.control.NumericEditField
        sPbetaEditFieldLabelMPELQ       matlab.ui.control.Label
        PbetaEditFieldMPELQ             matlab.ui.control.NumericEditField
        sWFEbetaEditFieldLabelMPELQ     matlab.ui.control.Label
        WFEbetaEditFieldMPELQ           matlab.ui.control.NumericEditField
        sWFEEditFieldLabelMPELQ         matlab.ui.control.Label
        WFEEditFieldMPELQ               matlab.ui.control.NumericEditField
        sTaEditFieldLabelMPELQ          matlab.ui.control.Label
        TaEditFieldMPELQ                matlab.ui.control.NumericEditField
        staudEditFieldLabelMPELQ        matlab.ui.control.Label
        taudEditFieldMPELQ              matlab.ui.control.NumericEditField
        sMsEditFieldLabelMPELQ          matlab.ui.control.Label
        EmlEditFieldMPELQ               matlab.ui.control.NumericEditField
        sN0EditFieldLabelMPELQ          matlab.ui.control.Label
        EtadEditFieldMPELQ              matlab.ui.control.NumericEditField
        sPAEditFieldLabeMPELQ           matlab.ui.control.Label
        PAEditFieldMPELQ                matlab.ui.control.NumericEditField
        UIAxesMPELQ                     matlab.ui.control.UIAxes
        PDMLTab_2                       matlab.ui.container.Tab
        PDMLLabel_2                     matlab.ui.control.Label
        DECISIONPanel_PDML              matlab.ui.container.Panel
        FIGURES_PDML                    matlab.ui.control.Button
        INITIAL_PDML                    matlab.ui.control.Button
        USEPMCDATABRBButton_PDML        matlab.ui.control.Button
        UIAxesPDML_1                    matlab.ui.control.UIAxes
        UIAxesPDML_2                    matlab.ui.control.UIAxes
        Tab_4                           matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Button                          matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value, 'ENGLISH')
                close (app.PDDEFENSESOFTWARENUAAUIFigure);
                PD_DEFENSE_SOFTWARE_ENGLISH;
            end
        end

        % Image clicked function: USFLAG
        function USFLAGImageClicked(app, event)
                close (app.PDDEFENSESOFTWARENUAAUIFigure);
                PD_DEFENSE_SOFTWARE_ENGLISH;     
        end

        % Value changed function: PercentValue
        function PercentValueValueChanged(app, event)
            app.PercentValue.Value;
        end

        % Value changed function: TrialsEditFieldPDMODEL
        function TrialsEditFieldValuePDMODELChanged(app, event)
            app.TrialsEditFieldPDMODEL.Value;
        end

        % Value changed function: VectorsEditFieldPDMODEL
        function VectorsEditFieldValuePDMODELChanged(app, event)
            app.VectorsEditFieldPDMODEL.Value;
        end

        % Value changed function: PAEditFieldPDMODEL
        function PAEditFieldValuePDMODELChanged(app, event)
            app.PAEditFieldPDMODEL.Value;
        end

        % Callback function
        function N0EditFieldValuePDMODELChanged(app, event)
            app.N0EditFieldPDMODEL.Value;
        end

        % Callback function
        function MsEditFieldValuePDMODELChanged(app, event)
            app.MsEditFieldPDMODEL.Value;
        end

        % Callback function
        function TaudEditFieldValuePDMODELChanged(app, event)
            app.TaudEditFieldPDMODEL.Value;
        end

        % Callback function
        function TaEditFieldValuePDMODELChanged(app, event)
            app.TaEditFieldPDMODEL.Value;
        end

        % Callback function
        function WFEEditFieldValuePDMODELChanged(app, event)
            app.WFEEditFieldPDMODEL.Value;
        end

        % Value changed function: WFEbetaEditFieldPDMODEL
        function WFEbetaEditFieldValuePDMODELChanged(app, event)
           app.WFEbetaEditFieldPDMODEL.Value;
        end

        % Value changed function: PbetaEditFieldPDMODEL
        function PbetaPDMODELEditFieldValueChanged(app, event)
            app.PbetaEditFieldPDMODEL.Value;
        end

        % Callback function
        function TcEditFieldValuePDMODELChanged(app, event)
            app.TcEditFieldPDMODEL.Value;
        end

        % Value changed function: SigmaPEditFieldPDMODEL
        function SigmaPEditFieldValuePDMODELChanged(app, event)
            app.SigmaPEditFieldPDMODEL.Value;
        end

        % Callback function: Button_2, UIAxes
        function ALLSIGNALSButtonPushed(app, event)
%% Top-level Monte-Carlo Script for simulating Pincer Observables
% Author: Jason Gross
%APP Author: Moses Michael, NUAA.


% CHANGES: receive some simulation defaults for the pincer sim from the
% user
% set some simulation defaults for the pincer sim
s.PA = app.PAEditFieldPDMODEL.Value;
s.N0 = app.N0EditFieldPDMODEL.Value;
s.Ms = app.MsEditFieldPDMODEL.Value;
s.taud = app.TaudEditFieldPDMODEL.Value;
s.Ta = app.TaEditFieldPDMODEL.Value;
s.WFE = app.WFEEditFieldPDMODEL.Value;
s.WFEbeta = app.WFEbetaEditFieldPDMODEL.Value;
s.Pbeta = app.PbetaEditFieldPDMODEL.Value; 
s.Tc = app.TcEditFieldPDMODEL.Value;
s.sigmaP = app.SigmaPEditFieldPDMODEL.Value;


PNom=-140.0906; % parameter added to model
PNomLin=10^(PNom/10);

p.N = 1;
p.Tc = s.Tc;
Np=app.TrialsEditFieldPDMODEL.Value;% number of trials of theta
Nm=app.VectorsEditFieldPDMODEL.Value; % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3

% assumed prior distribution
pPriorClean=app.PRIODCleanEditField.Value;
pPriorMultipath=app.PRIODMultipathEditField.Value;
pPriorSpoof=app.PRIODSpoofingEditField.Value;
pPriorJam=app.PRIODJammingEditField.Value;


verbose=1;
if (verbose==1)
    app.Button_2.Text = 'Processing...';
    app.Button_2.Icon = 'loading.gif';
    h=waitbar(0,'Simulation Progress');
end

for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        % ranging from matched power to moderate overpowered
        p.muEtadB =1; 
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';  
    end
    
    obsSimConfigs(i)=s;
    thetaKeys(i)=p.i;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and pincer sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [pincerObsOut(i,j)] = simulatePincerObservables(s);
        
    end
    
    if(verbose==1)
        waitbar(i/Np);
    end    
end
close(h);

   if (verbose==1)
       app.Button_2.Text = 'Plotting...';
       h=waitbar(0,'Plot Progress');
   end
   
MarkerSize=1;
cdD=[];
cdP=[];
mpD=[];
mpP=[];
spD=[];
spP=[];
jmD=[];
jmP=[];
for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cdD=[cdD (norm(pincerObsOut(i,j).d))];
            cdP=[cdP P_WRT_Nom];
        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mpD=[mpD (norm(pincerObsOut(i,j).d))];
            mpP=[mpP P_WRT_Nom];
        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            spD=[spD (norm(pincerObsOut(i,j).d))];
            spP=[spP P_WRT_Nom];
        end
    else
        % paramSimConfig(i).i ==3
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jmD=[jmD (norm(pincerObsOut(i,j).d))];
            jmP=[jmP P_WRT_Nom];
        end


    end
    
    %if(verbose==1)
  %  waitbar(i/Np);
    %end
end

% ---------------Plot resulrs

cla(app.UIAxes, 'reset')

hold(app.UIAxes,'on')

plot(app.UIAxes, spD,spP,'r.','MarkerSize', 3);
plot(app.UIAxes, jmD,jmP,'b.','MarkerSize', 3);
plot(app.UIAxes, mpD,mpP,'k.','MarkerSize', 3);
plot(app.UIAxes, cdD,cdP,'g.','MarkerSize', 3);


xlim(app.UIAxes,[0 30])
ylim(app.UIAxes,[-1 20])
xlabel(app.UIAxes,'Distortion D(eml)')
set(gca,'FontSize',14)
 ylabel(app.UIAxes,'Power (dB)')
 if(verbose==1)
     app.Button_2.Text = 'ALL SIGNALS';
     app.Button_2.Icon = 'tick.png';
     app.Button_6.Icon = '';
     app.Button_5.Icon = '';
     app.Button_4.Icon = '';
     app.Button_3.Icon = '';
    close(h);
 end


        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
%% Top-level Monte-Carlo Script for simulating Pincer Observables
% Author: Jason Gross
%APP Author: Moses Michael, NUAA.


% CHANGES: receive some simulation defaults for the pincer sim from the
% user
% set some simulation defaults for the pincer sim
s.PA = app.PAEditFieldPDMODEL.Value;
s.N0 = app.N0EditFieldPDMODEL.Value;
s.Ms = app.MsEditFieldPDMODEL.Value;
s.taud = app.TaudEditFieldPDMODEL.Value;
s.Ta = app.TaEditFieldPDMODEL.Value;
s.WFE = app.WFEEditFieldPDMODEL.Value;
s.WFEbeta = app.WFEbetaEditFieldPDMODEL.Value;
s.Pbeta = app.PbetaEditFieldPDMODEL.Value; 
s.Tc = app.TcEditFieldPDMODEL.Value;
s.sigmaP = app.SigmaPEditFieldPDMODEL.Value;


PNom=-140.0906; % parameter added to model
PNomLin=10^(PNom/10);

p.N = 1;
p.Tc = s.Tc;
Np=app.TrialsEditFieldPDMODEL.Value;% number of trials of theta
Nm=app.VectorsEditFieldPDMODEL.Value; % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3

% assumed prior distribution
pPriorClean=app.PRIODCleanEditField.Value;
pPriorMultipath=app.PRIODMultipathEditField.Value;
pPriorSpoof=app.PRIODSpoofingEditField.Value;
pPriorJam=app.PRIODJammingEditField.Value;


verbose=1;
if (verbose==1)
    app.Button_3.Text = 'Processing...';
    app.Button_3.Icon = 'loading.gif';
    h=waitbar(0,'Simulation Progress');
end

for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        % ranging from matched power to moderate overpowered
        p.muEtadB =1; 
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';  
    end
    
    obsSimConfigs(i)=s;
    thetaKeys(i)=p.i;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and pincer sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [pincerObsOut(i,j)] = simulatePincerObservables(s);
        
    end
    
    if(verbose==1)
        waitbar(i/Np);
    end    
end
close(h);

   if (verbose==1)
       app.Button_3.Text = 'Plotting...';
       h=waitbar(0,'Plot Progress');
   end
   
MarkerSize=1;
cdD=[];
cdP=[];
mpD=[];
mpP=[];
spD=[];
spP=[];
jmD=[];
jmP=[];
for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cdD=[cdD (norm(pincerObsOut(i,j).d))];
            cdP=[cdP P_WRT_Nom];
        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mpD=[mpD (norm(pincerObsOut(i,j).d))];
            mpP=[mpP P_WRT_Nom];
        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            spD=[spD (norm(pincerObsOut(i,j).d))];
            spP=[spP P_WRT_Nom];
        end
    else
        % paramSimConfig(i).i ==3
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jmD=[jmD (norm(pincerObsOut(i,j).d))];
            jmP=[jmP P_WRT_Nom];
        end


    end
    
    %if(verbose==1)
  %  waitbar(i/Np);
    %end
end

% ---------------Plot results

cla(app.UIAxes, 'reset')

hold(app.UIAxes,'on')


plot(app.UIAxes, cdD,cdP,'g.','MarkerSize', 3);


xlim(app.UIAxes,[0 30])
ylim(app.UIAxes,[-1 20])
xlabel(app.UIAxes,'Distortion D(eml)')
set(gca,'FontSize',14)
 ylabel(app.UIAxes,'Power (dB)')
 if(verbose==1)
     app.Button_3.Text = 'CLEAN';
     app.Button_3.Icon = 'tick.png';
     app.Button_6.Icon = '';
     app.Button_5.Icon = '';
     app.Button_4.Icon = '';
     app.Button_2.Icon = '';
    close(h);
 end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
%% Top-level Monte-Carlo Script for simulating Pincer Observables
% Author: Jason Gross
%APP Author: Moses Michael, NUAA.


% CHANGES: receive some simulation defaults for the pincer sim from the
% user
% set some simulation defaults for the pincer sim
s.PA = app.PAEditFieldPDMODEL.Value;
s.N0 = app.N0EditFieldPDMODEL.Value;
s.Ms = app.MsEditFieldPDMODEL.Value;
s.taud = app.TaudEditFieldPDMODEL.Value;
s.Ta = app.TaEditFieldPDMODEL.Value;
s.WFE = app.WFEEditFieldPDMODEL.Value;
s.WFEbeta = app.WFEbetaEditFieldPDMODEL.Value;
s.Pbeta = app.PbetaEditFieldPDMODEL.Value; 
s.Tc = app.TcEditFieldPDMODEL.Value;
s.sigmaP = app.SigmaPEditFieldPDMODEL.Value;


PNom=-140.0906; % parameter added to model
PNomLin=10^(PNom/10);

p.N = 1;
p.Tc = s.Tc;
Np=app.TrialsEditFieldPDMODEL.Value;% number of trials of theta
Nm=app.VectorsEditFieldPDMODEL.Value; % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3

% assumed prior distribution
pPriorClean=app.PRIODCleanEditField.Value;
pPriorMultipath=app.PRIODMultipathEditField.Value;
pPriorSpoof=app.PRIODSpoofingEditField.Value;
pPriorJam=app.PRIODJammingEditField.Value;


verbose=1;
if (verbose==1)
    app.Button_4.Text = 'Processing...';
    app.Button_4.Icon = 'loading.gif';
    h=waitbar(0,'Simulation Progress');
end

for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        % ranging from matched power to moderate overpowered
        p.muEtadB =1; 
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';  
    end
    
    obsSimConfigs(i)=s;
    thetaKeys(i)=p.i;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and pincer sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [pincerObsOut(i,j)] = simulatePincerObservables(s);
        
    end
    
    if(verbose==1)
        waitbar(i/Np);
    end    
end
close(h);

   if (verbose==1)
       app.Button_4.Text = 'Plotting...';
       h=waitbar(0,'Plot Progress');
   end
   
MarkerSize=1;
cdD=[];
cdP=[];
mpD=[];
mpP=[];
spD=[];
spP=[];
jmD=[];
jmP=[];
for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cdD=[cdD (norm(pincerObsOut(i,j).d))];
            cdP=[cdP P_WRT_Nom];
        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mpD=[mpD (norm(pincerObsOut(i,j).d))];
            mpP=[mpP P_WRT_Nom];
        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            spD=[spD (norm(pincerObsOut(i,j).d))];
            spP=[spP P_WRT_Nom];
        end
    else
        % paramSimConfig(i).i ==3
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jmD=[jmD (norm(pincerObsOut(i,j).d))];
            jmP=[jmP P_WRT_Nom];
        end


    end
    
    %if(verbose==1)
  %  waitbar(i/Np);
    %end
end

% ---------------Plot resulrs

cla(app.UIAxes, 'reset')

hold(app.UIAxes,'on')

plot(app.UIAxes, mpD,mpP,'k.','MarkerSize', 3);


xlim(app.UIAxes,[0 30])
ylim(app.UIAxes,[-1 20])
xlabel(app.UIAxes,'Distortion D(eml)')
set(gca,'FontSize',14)
 ylabel(app.UIAxes,'Power (dB)')
 if(verbose==1)
     app.Button_4.Text = 'MULTIPATH';
     app.Button_4.Icon = 'tick.png';
     app.Button_6.Icon = '';
     app.Button_5.Icon = '';
     app.Button_3.Icon = '';
     app.Button_2.Icon = '';
    close(h);
 end

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
%% Top-level Monte-Carlo Script for simulating Pincer Observables
% Author: Jason Gross
%APP Author: Moses Michael, NUAA.


% CHANGES: receive some simulation defaults for the pincer sim from the
% user
% set some simulation defaults for the pincer sim
s.PA = app.PAEditFieldPDMODEL.Value;
s.N0 = app.N0EditFieldPDMODEL.Value;
s.Ms = app.MsEditFieldPDMODEL.Value;
s.taud = app.TaudEditFieldPDMODEL.Value;
s.Ta = app.TaEditFieldPDMODEL.Value;
s.WFE = app.WFEEditFieldPDMODEL.Value;
s.WFEbeta = app.WFEbetaEditFieldPDMODEL.Value;
s.Pbeta = app.PbetaEditFieldPDMODEL.Value; 
s.Tc = app.TcEditFieldPDMODEL.Value;
s.sigmaP = app.SigmaPEditFieldPDMODEL.Value;


PNom=-140.0906; % parameter added to model
PNomLin=10^(PNom/10);

p.N = 1;
p.Tc = s.Tc;
Np=app.TrialsEditFieldPDMODEL.Value;% number of trials of theta
Nm=app.VectorsEditFieldPDMODEL.Value; % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3

% assumed prior distribution
pPriorClean=app.PRIODCleanEditField.Value;
pPriorMultipath=app.PRIODMultipathEditField.Value;
pPriorSpoof=app.PRIODSpoofingEditField.Value;
pPriorJam=app.PRIODJammingEditField.Value;


verbose=1;
if (verbose==1)
    app.Button_5.Text = 'Processing...';
    app.Button_5.Icon = 'loading.gif';
    h=waitbar(0,'Simulation Progress');
end

for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        % ranging from matched power to moderate overpowered
        p.muEtadB =1; 
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
        
        
    end
    obsSimConfigs(i)=s;
    thetaKeys(i)=p.i;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and pincer sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [pincerObsOut(i,j)] = simulatePincerObservables(s);
        
    end
    
    if(verbose==1)
        waitbar(i/Np);
    end    
end
close(h);

   if (verbose==1)
       app.Button_5.Text = 'Plotting...';
       h=waitbar(0,'Plot Progress');
   end
   
MarkerSize=1;
cdD=[];
cdP=[];
mpD=[];
mpP=[];
spD=[];
spP=[];
jmD=[];
jmP=[];
for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cdD=[cdD (norm(pincerObsOut(i,j).d))];
            cdP=[cdP P_WRT_Nom];
        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mpD=[mpD (norm(pincerObsOut(i,j).d))];
            mpP=[mpP P_WRT_Nom];
        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            spD=[spD (norm(pincerObsOut(i,j).d))];
            spP=[spP P_WRT_Nom];
        end
    else
        % paramSimConfig(i).i ==3
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jmD=[jmD (norm(pincerObsOut(i,j).d))];
            jmP=[jmP P_WRT_Nom];
        end


    end
    
    %if(verbose==1)
  %  waitbar(i/Np);
    %end
end

% ---------------Plot resulrs

cla(app.UIAxes, 'reset')

hold(app.UIAxes,'on')
plot(app.UIAxes, spD,spP,'r.','MarkerSize', 3);


xlim(app.UIAxes,[0 30])
ylim(app.UIAxes,[-1 20])
xlabel(app.UIAxes,'Distortion D(eml)')
set(gca,'FontSize',14)
 ylabel(app.UIAxes,'Power (dB)')
 if(verbose==1)
     app.Button_5.Text = 'SPOOFING';
     app.Button_5.Icon = 'tick.png';
     app.Button_6.Icon = '';
     app.Button_4.Icon = '';
     app.Button_3.Icon = '';
     app.Button_2.Icon = '';
    close(h);
 end

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %% Top-level Monte-Carlo Script for simulating Pincer Observables
% Author: Jason Gross
%APP Author: Moses Michael, NUAA.


% CHANGES: receive some simulation defaults for the pincer sim from the
% user
% set some simulation defaults for the pincer sim
s.PA = app.PAEditFieldPDMODEL.Value;
s.N0 = app.N0EditFieldPDMODEL.Value;
s.Ms = app.MsEditFieldPDMODEL.Value;
s.taud = app.TaudEditFieldPDMODEL.Value;
s.Ta = app.TaEditFieldPDMODEL.Value;
s.WFE = app.WFEEditFieldPDMODEL.Value;
s.WFEbeta = app.WFEbetaEditFieldPDMODEL.Value;
s.Pbeta = app.PbetaEditFieldPDMODEL.Value; 
s.Tc = app.TcEditFieldPDMODEL.Value;
s.sigmaP = app.SigmaPEditFieldPDMODEL.Value;


PNom=-140.0906; % parameter added to model
PNomLin=10^(PNom/10);

p.N = 1;
p.Tc = s.Tc;
Np=app.TrialsEditFieldPDMODEL.Value;% number of trials of theta
Nm=app.VectorsEditFieldPDMODEL.Value; % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3

% assumed prior distribution
pPriorClean=app.PRIODCleanEditField.Value;
pPriorMultipath=app.PRIODMultipathEditField.Value;
pPriorSpoof=app.PRIODSpoofingEditField.Value;
pPriorJam=app.PRIODJammingEditField.Value;


verbose=1;
if (verbose==1)
    app.Button_6.Text = 'Processing...';
    app.Button_6.Icon = 'loading.gif';
    h=waitbar(0,'Simulation Progress');
end

for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        % ranging from matched power to moderate overpowered
        p.muEtadB =1; 
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
        
        
    end
    obsSimConfigs(i)=s;
    thetaKeys(i)=p.i;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and pincer sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [pincerObsOut(i,j)] = simulatePincerObservables(s);
        
    end
    
        if(verbose==1)
        waitbar(i/Np);
    end    
end
close(h);

   if (verbose==1) 
       app.Button_6.Text = 'Plotting...';
       h=waitbar(0,'Plot Progress');
   end
   
MarkerSize=1;
cdD=[];
cdP=[];
mpD=[];
mpP=[];
spD=[];
spP=[];
jmD=[];
jmP=[];
for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cdD=[cdD (norm(pincerObsOut(i,j).d))];
            cdP=[cdP P_WRT_Nom];
        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mpD=[mpD (norm(pincerObsOut(i,j).d))];
            mpP=[mpP P_WRT_Nom];
        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            spD=[spD (norm(pincerObsOut(i,j).d))];
            spP=[spP P_WRT_Nom];
        end
    else
        % paramSimConfig(i).i ==3
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jmD=[jmD (norm(pincerObsOut(i,j).d))];
            jmP=[jmP P_WRT_Nom];
        end


    end
    
    %if(verbose==1)
  %  waitbar(i/Np);
    %end
end

% ---------------Plot resulrs

cla(app.UIAxes, 'reset')

hold(app.UIAxes,'on')
plot(app.UIAxes, jmD,jmP,'b.','MarkerSize', 3);


xlim(app.UIAxes,[0 30])
ylim(app.UIAxes,[-1 20])
xlabel(app.UIAxes,'Distortion D(eml)')
set(gca,'FontSize',14)
 ylabel(app.UIAxes,'Power (dB)')
 if(verbose==1)
     app.Button_6.Text = 'JAMMING';
     app.Button_6.Icon = 'tick.png';
     app.Button_5.Icon = '';
     app.Button_4.Icon = '';
     app.Button_3.Icon = '';
     app.Button_2.Icon = '';
    close(h);
 end

        end

        % Button pushed function: PMCButton
        function PMCButtonPushed(app, event)
%% Top-level Monte-Carlo Script for simulating Pincer Observables
% Author: Jason Gross
%APP Author: Moses Michael, NUAA.


% CHANGES: receive some simulation defaults for the pincer sim from the
% user
% set some simulation defaults for the pincer sim
s.PA = app.PAEditFieldPDMODEL.Value;
s.N0 = app.N0EditFieldPDMODEL.Value;
s.Ms = app.MsEditFieldPDMODEL.Value;
s.taud = app.TaudEditFieldPDMODEL.Value;
s.Ta = app.TaEditFieldPDMODEL.Value;
s.WFE = app.WFEEditFieldPDMODEL.Value;
s.WFEbeta = app.WFEbetaEditFieldPDMODEL.Value;
s.Pbeta = app.PbetaEditFieldPDMODEL.Value; 
s.Tc = app.TcEditFieldPDMODEL.Value;
s.sigmaP = app.SigmaPEditFieldPDMODEL.Value;

PNom=-140.0906; % parameter added to model
PNomLin=10^(PNom/10);

p.N = 1;
p.Tc = s.Tc;
Np=app.TrialsEditFieldPDMODEL.Value;% number of trials of theta
Nm=app.VectorsEditFieldPDMODEL.Value; % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3

% assumed prior distribution
pPriorClean=app.PRIODCleanEditField.Value;
pPriorMultipath=app.PRIODMultipathEditField.Value;
pPriorSpoof=app.PRIODSpoofingEditField.Value;
pPriorJam=app.PRIODJammingEditField.Value;


verbose=1;
if (verbose==1)
    app.PMCButton.Text = 'PROCESSING DATA...';
    app.PMCButton.Icon = 'loading.gif';
    h=waitbar(0,'Updating Pincer MonteCarlo Data');
end

for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        % ranging from matched power to moderate overpowered
        p.muEtadB =1; 
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
        
        
    end
    obsSimConfigs(i)=s;
    thetaKeys(i)=p.i;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and pincer sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [pincerObsOut(i,j)] = simulatePincerObservables(s);
        
    end
    
   if(verbose==1)
    waitbar(i/Np);
    end
    
end

save -v7.3 pincerMonteCarloData.mat
app.PMCButton.Text = 'Update PMC Data';
app.PMCButton.Icon = 'update.png';
close(h);
clear h;
        end

        % Button pushed function: MCButton
        function MCButtonPushed(app, event)
%% Top-level Monte-Carlo Script for simulating Pincer Observables
% Author: Jason Gross
%APP Author: Moses Michael, NUAA.


% CHANGES: receive some simulation defaults for the pincer sim from the
% user
% set some simulation defaults for the pincer sim
s.PA = app.PAEditFieldPDMODEL.Value;
s.N0 = app.N0EditFieldPDMODEL.Value;
s.Ms = app.MsEditFieldPDMODEL.Value;
s.taud = app.TaudEditFieldPDMODEL.Value;
s.Ta = app.TaEditFieldPDMODEL.Value;
s.WFE = app.WFEEditFieldPDMODEL.Value;
s.WFEbeta = app.WFEbetaEditFieldPDMODEL.Value;
s.Pbeta = app.PbetaEditFieldPDMODEL.Value; 
s.Tc = app.TcEditFieldPDMODEL.Value;
s.sigmaP = app.SigmaPEditFieldPDMODEL.Value;


PNom=-140.0906; % parameter added to model
PNomLin=10^(PNom/10);

p.N = 1;
p.Tc = s.Tc;
Np=app.TrialsEditFieldPDMODEL.Value;% number of trials of theta
Nm=app.VectorsEditFieldPDMODEL.Value; % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3

% assumed prior distribution
pPriorClean=app.PRIODCleanEditField.Value;
pPriorMultipath=app.PRIODMultipathEditField.Value;
pPriorSpoof=app.PRIODSpoofingEditField.Value;
pPriorJam=app.PRIODJammingEditField.Value;


verbose=1;
if (verbose==1)
    app.MCButton.Text = 'PROCESSING DATA...';
    app.MCButton.Icon = 'loading.gif';
    h=waitbar(0,'Updating MonteCarlo Data');
end

for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        % ranging from matched power to moderate overpowered
        p.muEtadB =1; 
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
        
        
    end
    obsSimConfigs(i)=s;
    thetaKeys(i)=p.i;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and pincer sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [pincerObsOut(i,j)] = simulatePincerObservables(s);
        
    end
              
end


MarkerSize=1;
cdD=[];
cdP=[];
mpD=[];
mpP=[];
spD=[];
spP=[];
jmD=[];
jmP=[];
for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cdD=[cdD (norm(pincerObsOut(i,j).d))];
            cdP=[cdP P_WRT_Nom];
        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mpD=[mpD (norm(pincerObsOut(i,j).d))];
            mpP=[mpP P_WRT_Nom];
        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            spD=[spD (norm(pincerObsOut(i,j).d))];
            spP=[spP P_WRT_Nom];
        end
    else
        % paramSimConfig(i).i ==3
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jmD=[jmD (norm(pincerObsOut(i,j).d))];
            jmP=[jmP P_WRT_Nom];
        end


    end
    
    if(verbose==1)
    waitbar(i/Np);
    end
end

save -v7.3 mcData.mat
app.MCButton.Text = 'Update MC Data';
app.MCButton.Icon = 'update.png';
close(h);
        end

        % Button pushed function: Button_9
        function Button_9Pushed2(app, event)
            cla(app.UIAxes_2, 'reset')
            app.PercentValue.Value = 0;
            title(app.UIAxes_2,'Press buttons below for graphical results ');
        end

        % Value changed function: SamplesEditFieldTOPPINCER
        function SamplesEditFieldTOPPINCERValueChanged(app, event)
            app.SamplesEditFieldTOPPINCER.Value;
        end

        % Value changed function: POEditFieldTOPPINCER
        function POEditFieldTOPPINCERValueChanged(app, event)
            app.POEditFieldTOPPINCER.Value;
        end

        % Value changed function: PAEditFieldTOPPINCER
        function PAEditFieldTOPPINCERValueChanged(app, event)
            app.PAEditFieldTOPPINCER.Value;
        end

        % Value changed function: N0EditFieldTOPPINCER
        function N0EditFieldTOPPINCERValueChanged(app, event)
            app.N0EditFieldTOPPINCER.Value;
        end

        % Value changed function: MsEditFieldTOPPINCER
        function MsEditFieldTOPPINCERValueChanged(app, event)
            app.MsEditFieldTOPPINCER.Value;
        end

        % Value changed function: taudEditFieldTOPPINCER
        function taudEditFieldTOPPINCERValueChanged(app, event)
            app.taudEditFieldTOPPINCER.Value;
        end

        % Value changed function: TaEditFieldTOPPINCER
        function TaEditFieldTOPPINCERValueChanged(app, event)
            app.TaEditFieldTOPPINCER.Value;
        end

        % Value changed function: WFEEditFieldTOPPINCER
        function WFEEditFieldTOPPINCERValueChanged(app, event)
            app.WFEEditFieldTOPPINCER.Value;
        end

        % Value changed function: WFEbetaEditFieldTOPPINCER
        function WFEbetaEditFieldTOPPINCERValueChanged(app, event)
            app.WFEbetaEditFieldTOPPINCER.Value;
        end

        % Value changed function: PbetaEditFieldTOPPINCER
        function PbetaEditFieldTOPPINCERValueChanged(app, event)
            app.PbetaEditFieldTOPPINCER.Value;
        end

        % Value changed function: TcEditFieldTOPPINCER
        function TcEditFieldTOPPINCERValueChanged(app, event)
            app.TcEditFieldTOPPINCER.Value;
        end

        % Value changed function: SigmaPEditFieldTOPPINCER
        function SigmaPEditFieldTOPPINCERValueChanged(app, event)
            app.SigmaPEditFieldTOPPINCER.Value;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
% SCATTER OF EXCESS POWER AND DISTORTATION - FETCH AND PLOT IN APP
            
% topSimPincer.m
%
% Top-level pincer simulation script

%----- Setup
clc;
scenarioVec = {'null','moderate_multipath','severe_multipath', ...
               'matched_power_spoofing','overpowered_spoofing',...
               'weak_jamming','moderate_jamming','severe_jamming'};
scenario = scenarioVec{1};
s.PA = app.PAEditFieldTOPPINCER.Value;
s.N0 = app.N0EditFieldTOPPINCER.Value;
s.Ms = app.MsEditFieldTOPPINCER.Value;
s.taud = app.taudEditFieldTOPPINCER.Value;
s.Ta = app.TaEditFieldTOPPINCER.Value;
s.WFE = app.WFEEditFieldTOPPINCER.Value;
s.WFEbeta = app.WFEbetaEditFieldTOPPINCER.Value;
s.Pbeta = app.PbetaEditFieldTOPPINCER.Value;
s.Tc = app.TcEditFieldTOPPINCER.Value;
s.sigmaP = app.SigmaPEditFieldTOPPINCER.Value;

%----- Simulate parameters for scenario
p.N = 1;
p.Tc = s.Tc;
switch scenario
  case 'null'
    p.i = 0;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'moderate_multipath'
    p.i = 1;
    p.elDeg = 40;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'severe_multipath'
    p.i = 1;
    p.elDeg = 10;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'matched_power_spoofing'
    p.i = 2;
    p.elDeg = 5;
    p.muEtadB = 0;
    p.sigmaEtadB = 0.1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'overpowered_spoofing'
    p.i = 2;
    p.elDeg = 5;
    p.muEtadB = 10;
    p.sigmaEtadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'weak_jamming'
    p.i = 3;
    p.riceSdB = 3;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'moderate_jamming'
    p.i = 3;
    p.riceSdB = 10;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'severe_jamming'
    p.i = 3;
    p.riceSdB = 16;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
end
s.etadB = op.etadB;
s.Delta_tau = op.Delta_tau;
s.Delta_theta = op.Delta_theta;

%----- Simulate observables for scenario
Nsim = app.SamplesEditFieldTOPPINCER.Value;
dVec = zeros(Nsim,1);
PVec = zeros(Nsim,1);
PfullVec = zeros(Nsim,1);
for ii=1:Nsim
  [o] = simulatePincerObservables(s);
  dVec(ii) = o.d; PVec(ii) = o.P; PfullVec(ii) = o.Pfull;
end
% The final symmetric difference D is the modulus of d
DVec = abs(dVec);

%----- Plot results
cla(app.UIAxes_2, 'reset') 

% Choose P0 so that under scenario "clean" mean(PVec) = P0
P0 = app.POEditFieldTOPPINCER.Value; 
fprintf('Scenario: %s\n',scenario);
fprintf('etadB = %.1f dB\n', s.etadB);
fprintf('Delta_tau = %.2f chips\n', s.Delta_tau);
fprintf('Delta_theta = %.2f rad\n', s.Delta_theta);
plot(app.UIAxes_2, DVec,PVec - P0, '.');
grid(app.UIAxes_2, "on");
xlim(app.UIAxes_2,[0,30]);
ylim(app.UIAxes_2,[-3,20]);
xlabel(app.UIAxes_2,'D (Normalized IQ units)')
ylabel(app.UIAxes_2,'P - P_0 (dB)');
title(app.UIAxes_2,'Scatter plot of excess power and distortion');
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
% THEORETICAL DISTRIBUTION OF D UNDER CLEAN OR JAMMING SCENARIO- FETCH AND PLOT IN APP
            
% topSimPincer.m
%
% Top-level pincer simulation script

%----- Setup
clc;
scenarioVec = {'null','moderate_multipath','severe_multipath', ...
               'matched_power_spoofing','overpowered_spoofing',...
               'weak_jamming','moderate_jamming','severe_jamming'};
scenario = scenarioVec{1};
s.PA = app.PAEditFieldTOPPINCER.Value;
s.N0 = app.N0EditFieldTOPPINCER.Value;
s.Ms = app.MsEditFieldTOPPINCER.Value;
s.taud = app.taudEditFieldTOPPINCER.Value;
s.Ta = app.TaEditFieldTOPPINCER.Value;
s.WFE = app.WFEEditFieldTOPPINCER.Value;
s.WFEbeta = app.WFEbetaEditFieldTOPPINCER.Value;
s.Pbeta = app.PbetaEditFieldTOPPINCER.Value;
s.Tc = app.TcEditFieldTOPPINCER.Value;
s.sigmaP = app.SigmaPEditFieldTOPPINCER.Value;

%----- Simulate parameters for scenario
p.N = 1;
p.Tc = s.Tc;
switch scenario
  case 'null'
    p.i = 0;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'moderate_multipath'
    p.i = 1;
    p.elDeg = 40;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'severe_multipath'
    p.i = 1;
    p.elDeg = 10;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'matched_power_spoofing'
    p.i = 2;
    p.elDeg = 5;
    p.muEtadB = 0;
    p.sigmaEtadB = 0.1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'overpowered_spoofing'
    p.i = 2;
    p.elDeg = 5;
    p.muEtadB = 10;
    p.sigmaEtadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'weak_jamming'
    p.i = 3;
    p.riceSdB = 3;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'moderate_jamming'
    p.i = 3;
    p.riceSdB = 10;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'severe_jamming'
    p.i = 3;
    p.riceSdB = 16;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
end
s.etadB = op.etadB;
s.Delta_tau = op.Delta_tau;
s.Delta_theta = op.Delta_theta;

%----- Simulate observables for scenario
Nsim = app.SamplesEditFieldTOPPINCER.Value;
dVec = zeros(Nsim,1);
PVec = zeros(Nsim,1);
PfullVec = zeros(Nsim,1);
for ii=1:Nsim
  [o] = simulatePincerObservables(s);
  dVec(ii) = o.d; PVec(ii) = o.P; PfullVec(ii) = o.Pfull;
end
% The final symmetric difference D is the modulus of d
DVec = abs(dVec);


%----- Check theoretical distribution for D 
% Under the clean or jamming scenarios, the theoretical distribution of D is
% Rayleigh with scale parameter sigma_d^2
if(p.i == 0 || p.i == 3)
  sigma_d = sqrt(8*s.taud*o.beta^2*(o.sigmaN/o.sigmaN0)^2)
  sigma_d_from_samples = std(dVec)
  xVec = [0.001:0.001:7]';
  % Note that the Rayleigh distribution given in Eq. (13) of the paper as posted
  % on IEEE Explore is incorrect.  sigma_d^2 in the equation is twice as large
  % as it should be.  The equation below gives the correct expression.
  pD = (2*xVec/(sigma_d^2)).*exp(-xVec.^2/(sigma_d^2));
  
%----- Plot results
  cla(app.UIAxes_2, 'reset') 
  
  plot(app.UIAxes_2, xVec,pD);
  title(app.UIAxes_2,'Theoretical distribution of D under clean or jamming scenarios');
  xlabel(app.UIAxes_2,'x');
  ylabel(app.UIAxes_2,'p_D');
end;
        end

        % Button pushed function: DButton_2
        function DButton_2Pushed(app, event)
            % DISTORTATION AND EXCESS POWER PER D - FETCH AND PLOT IN APP
            
% topSimPincer.m
%
% Top-level pincer simulation script

%----- Setup
clc;
scenarioVec = {'null','moderate_multipath','severe_multipath', ...
               'matched_power_spoofing','overpowered_spoofing',...
               'weak_jamming','moderate_jamming','severe_jamming'};
scenario = scenarioVec{1};
s.PA = app.PAEditFieldTOPPINCER.Value;
s.N0 = app.N0EditFieldTOPPINCER.Value;
s.Ms = app.MsEditFieldTOPPINCER.Value;
s.taud = app.taudEditFieldTOPPINCER.Value;
s.Ta = app.TaEditFieldTOPPINCER.Value;
s.WFE = app.WFEEditFieldTOPPINCER.Value;
s.WFEbeta = app.WFEbetaEditFieldTOPPINCER.Value;
s.Pbeta = app.PbetaEditFieldTOPPINCER.Value;
s.Tc = app.TcEditFieldTOPPINCER.Value;
s.sigmaP = app.SigmaPEditFieldTOPPINCER.Value;

%----- Simulate parameters for scenario
p.N = 1;
p.Tc = s.Tc;
switch scenario
  case 'null'
    p.i = 0;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'moderate_multipath'
    p.i = 1;
    p.elDeg = 40;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'severe_multipath'
    p.i = 1;
    p.elDeg = 10;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'matched_power_spoofing'
    p.i = 2;
    p.elDeg = 5;
    p.muEtadB = 0;
    p.sigmaEtadB = 0.1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'overpowered_spoofing'
    p.i = 2;
    p.elDeg = 5;
    p.muEtadB = 10;
    p.sigmaEtadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'weak_jamming'
    p.i = 3;
    p.riceSdB = 3;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'moderate_jamming'
    p.i = 3;
    p.riceSdB = 10;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'severe_jamming'
    p.i = 3;
    p.riceSdB = 16;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
end
s.etadB = op.etadB;
s.Delta_tau = op.Delta_tau;
s.Delta_theta = op.Delta_theta;

%----- Simulate observables for scenario
Nsim = app.SamplesEditFieldTOPPINCER.Value;
dVec = zeros(Nsim,1);
PVec = zeros(Nsim,1);
PfullVec = zeros(Nsim,1);
for ii=1:Nsim
  [o] = simulatePincerObservables(s);
  dVec(ii) = o.d; PVec(ii) = o.P; PfullVec(ii) = o.Pfull;
end
% The final symmetric difference D is the modulus of d
DVec = abs(dVec);

%----- Plot results
cla(app.UIAxes_2, 'reset') 

hist(app.UIAxes_2,DVec,25);
xlabel(app.UIAxes_2,'D (Normalized IQ units)');
ylabel(app.UIAxes_2,'N');
xlim(app.UIAxes_2,[0 30]);
title(app.UIAxes_2,'Histograms of distortion and excess power - D (Normalized IQ units)');
        end

        % Button pushed function: PP0Button
        function PP0ButtonPushed(app, event)
% DISTORTATION AND EXCESS POWER PER P-P0 - FETCH AND PLOT IN APP

            
% topSimPincer.m
%
% Top-level pincer simulation script

%----- Setup
clc;
scenarioVec = {'null','moderate_multipath','severe_multipath', ...
               'matched_power_spoofing','overpowered_spoofing',...
               'weak_jamming','moderate_jamming','severe_jamming'};
scenario = scenarioVec{1};
s.PA = app.PAEditFieldTOPPINCER.Value;
s.N0 = app.N0EditFieldTOPPINCER.Value;
s.Ms = app.MsEditFieldTOPPINCER.Value;
s.taud = app.taudEditFieldTOPPINCER.Value;
s.Ta = app.TaEditFieldTOPPINCER.Value;
s.WFE = app.WFEEditFieldTOPPINCER.Value;
s.WFEbeta = app.WFEbetaEditFieldTOPPINCER.Value;
s.Pbeta = app.PbetaEditFieldTOPPINCER.Value;
s.Tc = app.TcEditFieldTOPPINCER.Value;
s.sigmaP = app.SigmaPEditFieldTOPPINCER.Value;

%----- Simulate parameters for scenario
p.N = 1;
p.Tc = s.Tc;
switch scenario
  case 'null'
    p.i = 0;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'moderate_multipath'
    p.i = 1;
    p.elDeg = 40;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'severe_multipath'
    p.i = 1;
    p.elDeg = 10;
    op = simulatePincerParameters(p);
    s.Pflag = 0;
  case 'matched_power_spoofing'
    p.i = 2;
    p.elDeg = 5;
    p.muEtadB = 0;
    p.sigmaEtadB = 0.1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'overpowered_spoofing'
    p.i = 2;
    p.elDeg = 5;
    p.muEtadB = 10;
    p.sigmaEtadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'weak_jamming'
    p.i = 3;
    p.riceSdB = 3;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'moderate_jamming'
    p.i = 3;
    p.riceSdB = 10;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
  case 'severe_jamming'
    p.i = 3;
    p.riceSdB = 16;
    p.riceSigmadB = 1;
    op = simulatePincerParameters(p);
    s.Pflag = 2;
end
s.etadB = op.etadB;
s.Delta_tau = op.Delta_tau;
s.Delta_theta = op.Delta_theta;

%----- Simulate observables for scenario
Nsim = app.SamplesEditFieldTOPPINCER.Value;
dVec = zeros(Nsim,1);
PVec = zeros(Nsim,1);
PfullVec = zeros(Nsim,1);
for ii=1:Nsim
  [o] = simulatePincerObservables(s);
  dVec(ii) = o.d; PVec(ii) = o.P; PfullVec(ii) = o.Pfull;
end
% The final symmetric difference D is the modulus of d
DVec = abs(dVec);

%----- Plot results
cla(app.UIAxes_2, 'reset') 

% Choose P0 so that under scenario "clean" mean(PVec) = P0
P0 = app.POEditFieldTOPPINCER.Value;
hist(app.UIAxes_2, PVec - P0,25);
xlabel(app.UIAxes_2,'P - P_0 (dB)');
ylabel(app.UIAxes_2,'N');
xlim(app.UIAxes_2,[-3 30]);
title(app.UIAxes_2,'Histograms of distortion and excess power - P - P_0 (dB)');
        end

        % Value changed function: PRIODCleanEditField
        function PRIODCleanEditFieldValueChanged(app, event)
            app.PRIODCleanEditField.Value;
        end

        % Value changed function: PRIODMultipathEditField
        function PRIODMultipathEditFieldValueChanged(app, event)
            app.PRIODMultipathEditField.Value;
        end

        % Value changed function: PRIODSpoofingEditField
        function PRIODSpoofingEditFieldValueChanged(app, event)
            app.PRIODSpoofingEditField.Value;
        end

        % Value changed function: PRIODJammingEditField
        function PRIODJammingEditFieldValueChanged(app, event)
            app.PRIODJammingEditField.Value;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             cla(app.UIAxes, 'reset')
             app.PercentValue.Value = 0;
             app.Button_5.Icon = '';
             app.Button_6.Icon = '';
             app.Button_4.Icon = '';
             app.Button_3.Icon = '';
             app.Button_2.Icon = '';  
        end

        % Button pushed function: INITIAL
        function TEST(app, event)
% create the evalution grid & evalate p(zk)incer
% Author: Jason Gross
%clc; clear; close all;

load pincerMonteCarloData.mat 
        
costType=0; % variable cost in theta 2, fixed 1, misclass 0
SAVEFIGS=0;
tau_eml=0.3;
power=-1.2:.35:20;
deml=0:.35:40;
[demlR,powerR]=meshgrid(deml,power);

% start a reasonable partition based on visual
% inspection of the scatter plot for initial deicison

% clean D=>[0,4) P=>[-1,1)
% multipath D=>[4,11] P=>[-1,1)
% jam  D=>[0,4) P=>[1,20]
% spoof D=>[4,35] P=>[1,20] && D=>[11,35] P=>[-1,1]

pincerDecision=zeros(size(demlR));
for i=1:length(power)
    for j=1:length(deml)
        if(power(i)<1 && deml(j)<2)
            pincerDecision(i,j)=1;
        elseif (power(i)<1)
            if(deml(j)>=2 && deml(j)<15)
                pincerDecision(i,j)=2;
            else
                pincerDecision(i,j)=2;
            end
        elseif (power(i)>=1 && power(i)<=5 && deml(j)>=6)
            pincerDecision(i,j)=3;
        elseif (power(i)>=5 && power(i)<=10 && deml(j)>=7)
            pincerDecision(i,j)=3;
        elseif (power(i)>=10 && deml(j)>=7.5)
            pincerDecision(i,j)=3;
        else
            pincerDecision(i,j)=4;
        end
    end
end

mymap=[0 1 0; 0 0 0; 1 0 0; 0 0 1];

%----- Plot results

colormap(app.UIAxesBRB1, mymap);
cmap = pcolor(app.UIAxesBRB1, deml,power,pincerDecision);
cmap.EdgeColor = 'none';

xlabel(app.UIAxesBRB1, 'Distortion |D(eml)|')
ylabel(app.UIAxesBRB1, 'Power (dB)')
xlim(app.UIAxesBRB1, [.05 30])
ylim(app.UIAxesBRB1, [-1 19.8])
title(app.UIAxesBRB1, 'Initial Pincer Decision Partions')
            

        end

        % Button pushed function: FIGURES
        function FIGURESButtonPushed(app, event)
% create the evalution grid & evalate p(zk)incer
% Author: Jason Gross
%clc; clear; close all;
load pincerMonteCarloData.mat
costType=0; % variable cost in theta 2, fixed 1, misclass 0
SAVEFIGS=0;
tau_eml=0.3;
power=-1.2:.35:20;
deml=0:.35:40;
[demlR,powerR]=meshgrid(deml,power);

% start a reasonable partition based on visual
% inspection of the scatter plot for initial deicison

% clean D=>[0,4) P=>[-1,1)
% multipath D=>[4,11] P=>[-1,1)
% jam  D=>[0,4) P=>[1,20]
% spoof D=>[4,35] P=>[1,20] && D=>[11,35] P=>[-1,1]

pincerDecision=zeros(size(demlR));
for i=1:length(power)
    for j=1:length(deml)
        if(power(i)<1 && deml(j)<2)
            pincerDecision(i,j)=1;
        elseif (power(i)<1)
            if(deml(j)>=2 && deml(j)<15)
                pincerDecision(i,j)=2;
            else
                pincerDecision(i,j)=2;
            end
        elseif (power(i)>=1 && power(i)<=5 && deml(j)>=6)
            pincerDecision(i,j)=3;
        elseif (power(i)>=5 && power(i)<=10 && deml(j)>=7)
            pincerDecision(i,j)=3;
        elseif (power(i)>=10 && deml(j)>=7.5)
            pincerDecision(i,j)=3;
        else
            pincerDecision(i,j)=4;
        end
    end
end

mymap=[0 1 0; 0 0 0; 1 0 0; 0 0 1];

figure

colormap(mymap);
h=pcolor(deml,power,pincerDecision);
set(h, 'EdgeColor', 'none');
xlabel('Distortion |D(eml)|')
ylabel('Power (dB)')
xlim([.05 30])
ylim([-1 19.8])
set(gca,'FontSize',14)
title('Initial Pincer Decision Partions')


%  go through all the simulated observations and determine the grid
%  location, or GridKey.  Keeping track of these will speed up the cost
%  optimzation
dataKeys=zeros(Np,Nm);
powerSimObs=zeros(Np,Nm);
demlSimObs=zeros(Np,Nm);
thetas=zeros(Np,Nm);

deltaThetas=zeros(Np,Nm);
deltaTaus=zeros(Np,Nm);
etadBs=zeros(Np,Nm);

for i=1:Np
    for j=1:Nm
        PLinNoisey= 10^(pincerObsOut(i,j).P/10);
        P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
        p=P_WRT_Nom;
        d=norm(pincerObsOut(i,j).d);
        
        [c powBinLocation] = min(abs(power-p));
        [c demlBinLocation] = min(abs(deml-d));
        
        % determine unique 1D key within the 2D grid
        dataKeys(i,j)=(demlBinLocation)*length(deml)+powBinLocation;
        powerSimObs(i,j)=p;
        demlSimObs(i,j)=d;
        % although there is only one unique theta per set of Nm obs
        % storing this way makes keying the data easier in the next step
        thetas(i,j)=thetaKeys(i);
        deltaThetas(i,j)=obsSimConfigs(i).Delta_theta;
        deltaTaus(i,j)=obsSimConfigs(i).Delta_tau;
        etadBs(i,j)=obsSimConfigs(i).etadB;
        
    end
end

% go through all sim obs & determine the cost of the sim obs and all other sim obs of the same key
% also check cost if other hypotheses were accepted
% would the cost be reduced by accepting a different hypothesis. If so, change the
% deicison.  for efficiency, keep track of the keys that have been checked

keysAlreadyChecked=[];
changedDecisionIndex=1;
if(SAVEFIGS)
    figSaveStr=sprintf('pincerDec%08d.png',changedDecisionIndex);
    saveas(gcf,figSaveStr);
end
costChange=0;
firstIteration=1;
lastPincerDecision=pincerDecision;
while(sum(sum(pincerDecision-lastPincerDecision))~=0 || firstIteration==1 )
    firstIteration=0;
    lastPincerDecision=pincerDecision;
    for i=1:Np
        for j=1:Nm
            key=dataKeys(i,j);
            % only check each grid key once
            if find(key==keysAlreadyChecked)
                continue
            end
            % determine if on a decision boundary, if not, continue
            
            onDecisionBoundary=0;
            powLoc=mod(key,length(deml));
            
            demlLoc=floor((key)/length(deml));
            if((demlLoc>1))&& (demlLoc<length(deml)) &&( (powLoc>1)&&(powLoc<length(power)))
                % disp('Here')
                if powLoc<length(power)
                    if(pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc+1,demlLoc))
                        onDecisionBoundary=1;
                    end
                end
                if powLoc<length(power) && demlLoc<length(deml)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc+1,demlLoc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(demlLoc<length(deml))
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc,demlLoc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc-1,demlLoc))
                        onDecisionBoundary=1;
                    end
                end
                if(demlLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc,demlLoc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(demlLoc>1 && powLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc-1,demlLoc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc<length(power) && demlLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc+1,demlLoc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1 && demlLoc<length(deml))
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc-1,demlLoc+1))
                        onDecisionBoundary=1;
                    end
                end
            end
            if(onDecisionBoundary==0)
                continue
            else
                disp('Evaluating')
            end
            
            keysAlreadyChecked=[keysAlreadyChecked key];
            
            % evaluate cost of current decision and all the others
            % for this grid key
            decisionOfGridKey=interp2(demlR,powerR,pincerDecision,...
                demlSimObs(i,j),powerSimObs(i,j),'nearest');
            
            thetasInGridKey=thetas(dataKeys==key);
            etadBsinGridKey=etadBs(dataKeys==key);
            deltaTauIAsInGridKey=deltaTaus(dataKeys==key);
            deltaThetaIAsInGridKey=deltaThetas(dataKeys==key);
            
            currentCost=0;
            otherDecisions=find([1,2,3,4]~=decisionOfGridKey);
            otherDecisionCosts=[0,0,0];
            
            for k=1:length(thetasInGridKey)
                %% this constant cost mat is what will need to be made variable
                currentCost=currentCost+pincerCostFunction(decisionOfGridKey,...
                    thetasInGridKey(k)+1, etadBsinGridKey(k) ,deltaTauIAsInGridKey(k), ...
                    deltaThetaIAsInGridKey(k),tau_eml,costType);
                
                for l=1:length(otherDecisions)
                    otherDecisionCosts(l)=otherDecisionCosts(l)+pincerCostFunction(otherDecisions(l),...
                        thetasInGridKey(k)+1,etadBsinGridKey(k) ,...
                        deltaTauIAsInGridKey(k), deltaThetaIAsInGridKey(k),tau_eml,costType);
                end
            end
            
            % determine if cost can be reduced, if so, accept lowest cost
            if(sum(otherDecisionCosts<currentCost))
                newDecision=otherDecisions(otherDecisionCosts==min(otherDecisionCosts));
                disp([j,i])
                % get grid location from key
                
                powLoc=mod(key,length(deml));
                %powLoc=powBinLoc+1;
                demlLoc=floor((key)/length(deml));
                
                %disp('Loc')
                %disp([demlLoc powLoc])
                pincerDecision(powLoc,demlLoc)=newDecision(1);
                changedDecisionIndex=changedDecisionIndex+1;
                %save the cost improvement history for plotting
                costChange(changedDecisionIndex)=costChange(changedDecisionIndex-1)+...
                    (currentCost-min(otherDecisionCosts));
                
                colormap(mymap);
                h=pcolor(deml,power,pincerDecision);
                set(h, 'EdgeColor', 'none');
                xlabel('Distortion |D(eml)|')
                ylabel('Power (dB)')
                xlim([.05 30])
                set(gca,'FontSize',14)
                if(SAVEFIGS)
                    figSaveStr=sprintf('pincerDec%08d.png',changedDecisionIndex);
                    saveas(gcf,figSaveStr);
                end
            end
        end
    end
end

% eliminate potential of island decisions
[M,N]=size(pincerDecision);
for i=2:M-1
    for j=2:N-1
        
        decision=pincerDecision(i,j);
        boundaryDecision=pincerDecision(i,j+1);
        if ((pincerDecision(i,j-1)==boundaryDecision)&&...
            (pincerDecision(i-1,j)==boundaryDecision)&&...
            (pincerDecision(i+1,j)==boundaryDecision)&&...
             (decision~=boundaryDecision))
            disp('Found Island')
            pincerDecision(i,j)=boundaryDecision;
        end
    end
end


figure,colormap(mymap);
h=pcolor(deml,power,pincerDecision);
set(h, 'EdgeColor', 'none');
xlabel('Distortion |D(eml)|')
ylabel('Power (dB)')
xlim([.05 30])
set(gca,'FontSize',14)            
        end

        % Button pushed function: USEPMCDATABRBButton
        function USEPMCDATABRB_ButtonPushed(app, event)
% create the evalution grid & evalate p(zk)incer
% Author: Jason Gross
%clc; clear; close all;
load pincerMonteCarloData.mat
costType=0; % variable cost in theta 2, fixed 1, misclass 0
SAVEFIGS=0;
tau_eml=0.3;
power=-1.2:.35:20;
deml=0:.35:40;
[demlR,powerR]=meshgrid(deml,power);

% start a reasonable partition based on visual
% inspection of the scatter plot for initial deicison

% clean D=>[0,4) P=>[-1,1)
% multipath D=>[4,11] P=>[-1,1)
% jam  D=>[0,4) P=>[1,20]
% spoof D=>[4,35] P=>[1,20] && D=>[11,35] P=>[-1,1]

pincerDecision=zeros(size(demlR));
for i=1:length(power)
    for j=1:length(deml)
        if(power(i)<1 && deml(j)<2)
            pincerDecision(i,j)=1;
        elseif (power(i)<1)
            if(deml(j)>=2 && deml(j)<15)
                pincerDecision(i,j)=2;
            else
                pincerDecision(i,j)=2;
            end
        elseif (power(i)>=1 && power(i)<=5 && deml(j)>=6)
            pincerDecision(i,j)=3;
        elseif (power(i)>=5 && power(i)<=10 && deml(j)>=7)
            pincerDecision(i,j)=3;
        elseif (power(i)>=10 && deml(j)>=7.5)
            pincerDecision(i,j)=3;
        else
            pincerDecision(i,j)=4;
        end
    end
end

mymap=[0 1 0; 0 0 0; 1 0 0; 0 0 1];

colormap(app.UIAxesBRB1, mymap);
cmap = pcolor(app.UIAxesBRB1, deml,power,pincerDecision);
cmap.EdgeColor = 'none';

xlabel(app.UIAxesBRB1, 'Distortion |D(eml)|')
ylabel(app.UIAxesBRB1, 'Power (dB)')
xlim(app.UIAxesBRB1, [.05 30])
ylim(app.UIAxesBRB1, [-1 19.8])
title(app.UIAxesBRB1, 'Initial Pincer Decision Partions')


%  go through all the simulated observations and determine the grid
%  location, or GridKey.  Keeping track of these will speed up the cost
%  optimzation
dataKeys=zeros(Np,Nm);
powerSimObs=zeros(Np,Nm);
demlSimObs=zeros(Np,Nm);
thetas=zeros(Np,Nm);

deltaThetas=zeros(Np,Nm);
deltaTaus=zeros(Np,Nm);
etadBs=zeros(Np,Nm);

for i=1:Np
    for j=1:Nm
        PLinNoisey= 10^(pincerObsOut(i,j).P/10);
        P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
        p=P_WRT_Nom;
        d=norm(pincerObsOut(i,j).d);
        
        [c powBinLocation] = min(abs(power-p));
        [c demlBinLocation] = min(abs(deml-d));
        
        % determine unique 1D key within the 2D grid
        dataKeys(i,j)=(demlBinLocation)*length(deml)+powBinLocation;
        powerSimObs(i,j)=p;
        demlSimObs(i,j)=d;
        % although there is only one unique theta per set of Nm obs
        % storing this way makes keying the data easier in the next step
        thetas(i,j)=thetaKeys(i);
        deltaThetas(i,j)=obsSimConfigs(i).Delta_theta;
        deltaTaus(i,j)=obsSimConfigs(i).Delta_tau;
        etadBs(i,j)=obsSimConfigs(i).etadB;
        
    end
end

% go through all sim obs & determine the cost of the sim obs and all other sim obs of the same key
% also check cost if other hypotheses were accepted
% would the cost be reduced by accepting a different hypothesis. If so, change the
% deicison.  for efficiency, keep track of the keys that have been checked

keysAlreadyChecked=[];
changedDecisionIndex=1;
if(SAVEFIGS)
    figSaveStr=sprintf('pincerDec%08d.png',changedDecisionIndex);
    saveas(gcf,figSaveStr);
end
costChange=0;
firstIteration=1;
lastPincerDecision=pincerDecision;
while(sum(sum(pincerDecision-lastPincerDecision))~=0 || firstIteration==1 )
    firstIteration=0;
    lastPincerDecision=pincerDecision;
    for i=1:Np
        for j=1:Nm
            key=dataKeys(i,j);
            % only check each grid key once
            if find(key==keysAlreadyChecked)
                continue
            end
            % determine if on a decision boundary, if not, continue
            
            onDecisionBoundary=0;
            powLoc=mod(key,length(deml));
            
            demlLoc=floor((key)/length(deml));
            if((demlLoc>1))&& (demlLoc<length(deml)) &&( (powLoc>1)&&(powLoc<length(power)))
                % disp('Here')
                if powLoc<length(power)
                    if(pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc+1,demlLoc))
                        onDecisionBoundary=1;
                    end
                end
                if powLoc<length(power) && demlLoc<length(deml)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc+1,demlLoc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(demlLoc<length(deml))
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc,demlLoc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc-1,demlLoc))
                        onDecisionBoundary=1;
                    end
                end
                if(demlLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc,demlLoc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(demlLoc>1 && powLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc-1,demlLoc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc<length(power) && demlLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc+1,demlLoc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1 && demlLoc<length(deml))
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc-1,demlLoc+1))
                        onDecisionBoundary=1;
                    end
                end
            end
            if(onDecisionBoundary==0)
                continue
            else
                disp('Evaluating')
            end
            
            keysAlreadyChecked=[keysAlreadyChecked key];
            
            % evaluate cost of current decision and all the others
            % for this grid key
            decisionOfGridKey=interp2(demlR,powerR,pincerDecision,...
                demlSimObs(i,j),powerSimObs(i,j),'nearest');
            
            thetasInGridKey=thetas(dataKeys==key);
            etadBsinGridKey=etadBs(dataKeys==key);
            deltaTauIAsInGridKey=deltaTaus(dataKeys==key);
            deltaThetaIAsInGridKey=deltaThetas(dataKeys==key);
            
            currentCost=0;
            otherDecisions=find([1,2,3,4]~=decisionOfGridKey);
            otherDecisionCosts=[0,0,0];
            
            for k=1:length(thetasInGridKey)
                %% this constant cost mat is what will need to be made variable
                currentCost=currentCost+pincerCostFunction(decisionOfGridKey,...
                    thetasInGridKey(k)+1, etadBsinGridKey(k) ,deltaTauIAsInGridKey(k), ...
                    deltaThetaIAsInGridKey(k),tau_eml,costType);
                
                for l=1:length(otherDecisions)
                    otherDecisionCosts(l)=otherDecisionCosts(l)+pincerCostFunction(otherDecisions(l),...
                        thetasInGridKey(k)+1,etadBsinGridKey(k) ,...
                        deltaTauIAsInGridKey(k), deltaThetaIAsInGridKey(k),tau_eml,costType);
                end
            end
            
            % determine if cost can be reduced, if so, accept lowest cost
            if(sum(otherDecisionCosts<currentCost))
                newDecision=otherDecisions(otherDecisionCosts==min(otherDecisionCosts));
                disp([j,i])
                % get grid location from key
                
                powLoc=mod(key,length(deml));
                %powLoc=powBinLoc+1;
                demlLoc=floor((key)/length(deml));
                
                %disp('Loc')
                %disp([demlLoc powLoc])
                pincerDecision(powLoc,demlLoc)=newDecision(1);
                changedDecisionIndex=changedDecisionIndex+1;
                %save the cost improvement history for plotting
                costChange(changedDecisionIndex)=costChange(changedDecisionIndex-1)+...
                    (currentCost-min(otherDecisionCosts));
                
                colormap(mymap);
                h=pcolor(deml,power,pincerDecision);
                set(h, 'EdgeColor', 'none');
                xlabel('Distortion |D(eml)|')
                ylabel('Power (dB)')
                xlim([.05 30])
                set(gca,'FontSize',14)
                if(SAVEFIGS)
                    figSaveStr=sprintf('pincerDec%08d.png',changedDecisionIndex);
                    saveas(gcf,figSaveStr);
                end
            end
        end
    end
end

% eliminate potential of island decisions
[M,N]=size(pincerDecision);
for i=2:M-1
    for j=2:N-1
        
        decision=pincerDecision(i,j);
        boundaryDecision=pincerDecision(i,j+1);
        if ((pincerDecision(i,j-1)==boundaryDecision)&&...
            (pincerDecision(i-1,j)==boundaryDecision)&&...
            (pincerDecision(i+1,j)==boundaryDecision)&&...
             (decision~=boundaryDecision))
            disp('Found Island')
            pincerDecision(i,j)=boundaryDecision;
        end
    end
end

colormap(app.UIAxesBRB1, mymap);
cmap = pcolor(app.UIAxesBRB1, deml,power,pincerDecision);
cmap.EdgeColor = 'none';

xlabel(app.UIAxesBRB1, 'Distortion |D(eml)|')
ylabel(app.UIAxesBRB1, 'Power (dB)')
xlim(app.UIAxesBRB1, [.05 30])
ylim(app.UIAxesBRB1, [-1 19.8])

        end

        % Value changed function: PRIODCleanEditFieldMPELQ
        function PRIODCleanEditFieldMPELQValueChanged(app, event)
            app.PRIODCleanEditFieldMPELQ.Value;
        end

        % Value changed function: PRIODMultipathEditFieldMPELQ
        function PRIODMultipathEditFieldMPELQValueChanged(app, event)
            app.PRIODMultipathEditFieldMPELQ.Value;
        end

        % Value changed function: PRIODSpoofingEditFieldMPELQ
        function PRIODSpoofingEditFieldMPELQValueChanged(app, event)
            app.PRIODSpoofingEditFieldMPELQ.Value;
        end

        % Value changed function: PRIODJammingEditFieldMPELQ
        function PRIODJammingEditFieldMPELQValueChanged(app, event)
            app.PRIODJammingEditFieldMPELQ.Value;
        end

        % Button pushed function: ClearButtonMPELQ
        function ClearButtonMPELQPushed(app, event)
            cla(app.UIAxesMPELQ, 'reset') 
            app.PercentValue.Value = 0;
             app.SPOOFINGButtonMPELQ.Icon = '';
             app.JAMMINGButtonMPELQ.Icon = '';
             app.MULTIPATHButtonMPELQ.Icon = '';
             app.CLEANButtonMPELQ.Icon = '';
             app.ALLSIGNALSButtonMPELQ.Icon = '';  
        end

        % Value changed function: TrialsEditFieldMPELQ
        function TrialsEditFieldMPELQValueChanged(app, event)
            app.TrialsEditFieldMPELQ.Value;
        end

        % Value changed function: VectorEditFieldMPELQ
        function VectorEditFieldMPELQValueChanged(app, event)
            app.VectorEditFieldMPELQ.Value;
        end

        % Value changed function: N0EditFieldMPELQ
        function N0EditFieldMPELQValueChanged(app, event)
            app.N0EditFieldMPELQ.Value;
        end

        % Value changed function: MsEditFieldMPELQ
        function MsEditFieldMPELQValueChanged(app, event)
            app.MsEditFieldMPELQ.Value;
        end

        % Value changed function: TrueDelayEditFieldMPELQ
        function TrueDelayEditFieldMPELQValueChanged(app, event)
            app.TrueDelayEditFieldMPELQ.Value;
        end

        % Value changed function: PAEditFieldMPELQ
        function PAEditFieldMPELQValueChanged(app, event)
            app.PAEditFieldMPELQ.Value;
        end

        % Value changed function: EtadEditFieldMPELQ
        function EtadEditFieldMPELQValueChanged(app, event)
            app.EtadEditFieldMPELQ.Value;
        end

        % Value changed function: EmlEditFieldMPELQ
        function EmlEditFieldMPELQValueChanged(app, event)
            app.EmlEditFieldMPELQ.Value;
        end

        % Value changed function: taudEditFieldMPELQ
        function taudEditFieldMPELQValueChanged(app, event)
            app.taudEditFieldMPELQ.Value;
        end

        % Value changed function: TaEditFieldMPELQ
        function TaEditFieldMPELQValueChanged(app, event)
            app.TaEditFieldMPELQ.Value;
        end

        % Value changed function: WFEEditFieldMPELQ
        function WFEEditFieldMPELQValueChanged(app, event)
            app.WFEEditFieldMPELQ.Value;
        end

        % Value changed function: WFEbetaEditFieldMPELQ
        function WFEbetaEditFieldMPELQValueChanged(app, event)
            app.WFEbetaEditFieldMPELQ.Value;
        end

        % Value changed function: PbetaEditFieldMPELQ
        function PbetaEditFieldMPELQValueChanged(app, event)
            app.PbetaEditFieldMPELQ.Value;
        end

        % Value changed function: TcEditFieldMPELQ
        function TcEditFieldMPELQValueChanged(app, event)
            app.TcEditFieldMPELQ.Value;
        end

        % Value changed function: sigmaPEditFieldMPELQ
        function sigmaPEditFieldMPELQValueChanged(app, event)
            app.sigmaPEditFieldMPELQ.Value;
        end

        % Value changed function: nTapsEditFieldMPELQ
        function nTapsEditFieldMPELQValueChanged(app, event)
            app.nTapsEditFieldMPELQ.Value;
        end

        % Button pushed function: ALLSIGNALSButtonMPELQ
        function ALLSIGNALSButtonMPELQPushed(app, event)
%% Top-level Monte-Carlo Script for simulating PD-ML Observables
% Author: Jason Gross with slight mods by Cagri Kilic

clc;close all;
% set some simulation defaults
s.PA = app.PAEditFieldMPELQ.Value;
s.etadB = app.EtadEditFieldMPELQ.Value;
s.N0 = app.N0EditFieldMPELQ.Value;
s.Ms = app.MsEditFieldMPELQ.Value;
s.eml = app.EmlEditFieldMPELQ.Value;
s.Ta = app.TaEditFieldMPELQ.Value;
s.WFE = app.WFEEditFieldMPELQ.Value;
s.WFEbeta = app.WFEbetaEditFieldMPELQ.Value;
s.Pbeta = app.PbetaEditFieldMPELQ.Value;
s.Tc = app.TcEditFieldMPELQ.Value;
s.sigmaP = app.sigmaPEditFieldMPELQ.Value;
s.taud = app.taudEditFieldMPELQ.Value;
s.trueDelaySigma=app.TrueDelayEditFieldMPELQ.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
% multipath estimator struct
%------------------------------------------------
s.nTaps=app.nTapsEditFieldMPELQ.Value; %select nTaps number as 5,7,15 or 41
%------------------------------------------------
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));


% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end


Np=app.TrialsEditFieldMPELQ.Value;% number of trials of theta
Nm=app.VectorEditFieldMPELQ.Value;  % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution
pPriorClean=app.PRIODCleanEditFieldMPELQ.Value;
pPriorMultipath=app.PRIODMultipathEditFieldMPELQ.Value;
pPriorSpoof=app.PRIODSpoofingEditFieldMPELQ.Value;
pPriorJam=app.PRIODJammingEditFieldMPELQ.Value;

verbose=1;
if (verbose==1)
    h=waitbar(0,'Simulation Progress');
    app.ALLSIGNALSButtonMPELQ.Icon = 'loading.gif';
    app.ALLSIGNALSButtonMPELQ.Text = 'Processing...';
    
end
for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
    
end
close(h);
clear h;

if (verbose==1)
    h=waitbar(0,'Plot Progress');
    pp.ALLSIGNALSButtonMPELQ.Icon = 'loading.gif';
    app.ALLSIGNALSButtonMPELQ.Text = 'Plotting Results...';
end
% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


% clear h
% delete h
% save -v7.3 mpeIQMonteCarloall.mat
cla(app.UIAxesMPELQ, 'reset')

semilogx(app.UIAxesMPELQ,sp1X,spP,'r.','MarkerSize', 5);
hold(app.UIAxesMPELQ,'on')
semilogx(app.UIAxesMPELQ,jm1X,jmP,'b.','MarkerSize', 5);
semilogx(app.UIAxesMPELQ,mp1X,mpP,'k.','MarkerSize', 5);
semilogx(app.UIAxesMPELQ,cd1X,cdP,'g.','MarkerSize', 5);

xlabel(app.UIAxesMPELQ,'$$RSS(\frac{1}{a_0} exp(-j\theta_0)(\xi_k - \hat{\xi_0}))$$','Interpreter','Latex')
ylabel(app.UIAxesMPELQ,'Power (dB)')
axis(app.UIAxesMPELQ, 'tight');
title(app.UIAxesMPELQ, '')

 if(verbose==1)
    app.ALLSIGNALSButtonMPELQ.Text = 'ALL SIGNALS';
    app.ALLSIGNALSButtonMPELQ.Icon = 'tick.png';
    app.JAMMINGButtonMPELQ.Icon = '';
    app.SPOOFINGButtonMPELQ.Icon = '';    
    app.MULTIPATHButtonMPELQ.Icon = '';
    app.CLEANButtonMPELQ.Icon = '';
    close(h);
 end
        end

        % Button pushed function: CLEANButtonMPELQ
        function CLEANButtonMPELQPushed(app, event)
%% Top-level Monte-Carlo Script for simulating PD-ML Observables
% Author: Jason Gross with slight mods by Cagri Kilic

clc;close all;
% set some simulation defaults
s.PA = app.PAEditFieldMPELQ.Value;
s.etadB = app.EtadEditFieldMPELQ.Value;
s.N0 = app.N0EditFieldMPELQ.Value;
s.Ms = app.MsEditFieldMPELQ.Value;
s.eml = app.EmlEditFieldMPELQ.Value;
s.Ta = app.TaEditFieldMPELQ.Value;
s.WFE = app.WFEEditFieldMPELQ.Value;
s.WFEbeta = app.WFEbetaEditFieldMPELQ.Value;
s.Pbeta = app.PbetaEditFieldMPELQ.Value;
s.Tc = app.TcEditFieldMPELQ.Value;
s.sigmaP = app.sigmaPEditFieldMPELQ.Value;
s.taud = app.taudEditFieldMPELQ.Value;
s.trueDelaySigma=app.TrueDelayEditFieldMPELQ.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
% multipath estimator struct
%------------------------------------------------
s.nTaps=app.nTapsEditFieldMPELQ.Value; %select nTaps number as 5,7,15 or 41
%------------------------------------------------
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));


% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end


Np=app.TrialsEditFieldMPELQ.Value;% number of trials of theta
Nm=app.VectorEditFieldMPELQ.Value;  % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution
pPriorClean=app.PRIODCleanEditFieldMPELQ.Value;
pPriorMultipath=app.PRIODMultipathEditFieldMPELQ.Value;
pPriorSpoof=app.PRIODSpoofingEditFieldMPELQ.Value;
pPriorJam=app.PRIODJammingEditFieldMPELQ.Value;

verbose=1;
if (verbose==1)
    h=waitbar(0,'Simulation Progress');
    app.CLEANButtonMPELQ.Icon = 'loading.gif';
    app.CLEANButtonMPELQ.Text = 'Processing...';
end
for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
    
end
close(h);
clear h;

if (verbose==1)
    h=waitbar(0,'Plot Progress');
    app.CLEANButtonMPELQ.Icon = 'loading.gif';
    app.CLEANButtonMPELQ.Text = 'Plotting...';
end
% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


% clear h
% delete h
% save -v7.3 mpeIQMonteCarloall.mat
cla(app.UIAxesMPELQ, 'reset')

hold(app.UIAxesMPELQ,'on')
semilogx(app.UIAxesMPELQ,cd1X,cdP,'g.','MarkerSize', 5);


xlabel(app.UIAxesMPELQ,'$$RSS(\frac{1}{a_0} exp(-j\theta_0)(\xi_k - \hat{\xi_0}))$$','Interpreter','Latex')
ylabel(app.UIAxesMPELQ,'Power (dB)')
axis(app.UIAxesMPELQ, 'tight');
title(app.UIAxesMPELQ, '')

 if(verbose==1)
    app.CLEANButtonMPELQ.Text = 'CLEAN';
    app.CLEANButtonMPELQ.Icon = 'tick.png';
    app.ALLSIGNALSButtonMPELQ.Icon = '';
    app.JAMMINGButtonMPELQ.Icon = '';
    app.SPOOFINGButtonMPELQ.Icon = '';    
    app.MULTIPATHButtonMPELQ.Icon = '';
    close(h);
 end            
        end

        % Button pushed function: MULTIPATHButtonMPELQ
        function MULTIPATHButtonMPELQPushed(app, event)
%% Top-level Monte-Carlo Script for simulating PD-ML Observables
% Author: Jason Gross with slight mods by Cagri Kilic

clc;close all;
% set some simulation defaults
s.PA = app.PAEditFieldMPELQ.Value;
s.etadB = app.EtadEditFieldMPELQ.Value;
s.N0 = app.N0EditFieldMPELQ.Value;
s.Ms = app.MsEditFieldMPELQ.Value;
s.eml = app.EmlEditFieldMPELQ.Value;
s.Ta = app.TaEditFieldMPELQ.Value;
s.WFE = app.WFEEditFieldMPELQ.Value;
s.WFEbeta = app.WFEbetaEditFieldMPELQ.Value;
s.Pbeta = app.PbetaEditFieldMPELQ.Value;
s.Tc = app.TcEditFieldMPELQ.Value;
s.sigmaP = app.sigmaPEditFieldMPELQ.Value;
s.taud = app.taudEditFieldMPELQ.Value;
s.trueDelaySigma=app.TrueDelayEditFieldMPELQ.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
% multipath estimator struct
%------------------------------------------------
s.nTaps=app.nTapsEditFieldMPELQ.Value; %select nTaps number as 5,7,15 or 41
%------------------------------------------------
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));


% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end


Np=app.TrialsEditFieldMPELQ.Value;% number of trials of theta
Nm=app.VectorEditFieldMPELQ.Value;  % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution
pPriorClean=app.PRIODCleanEditFieldMPELQ.Value;
pPriorMultipath=app.PRIODMultipathEditFieldMPELQ.Value;
pPriorSpoof=app.PRIODSpoofingEditFieldMPELQ.Value;
pPriorJam=app.PRIODJammingEditFieldMPELQ.Value;

verbose=1;
if (verbose==1)
    h=waitbar(0,'Simulation Progress');
    app.MULTIPATHButtonMPELQ.Icon = 'loading.gif';
    app.MULTIPATHButtonMPELQ.Text = 'Processing...';
    
end
for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
    
end
close(h);
clear h;

if (verbose==1)
    h=waitbar(0,'Plot Progress');
    pp.MULTIPATHButtonMPELQ.Icon = 'loading.gif';
    app.MULTIPATHButtonMPELQ.Text = 'Plotting ...';
end
% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


% clear h
% delete h
% save -v7.3 mpeIQMonteCarloall.mat
cla(app.UIAxesMPELQ, 'reset')


hold(app.UIAxesMPELQ,'on')
semilogx(app.UIAxesMPELQ,mp1X,mpP,'k.','MarkerSize', 5);

xlabel(app.UIAxesMPELQ,'$$RSS(\frac{1}{a_0} exp(-j\theta_0)(\xi_k - \hat{\xi_0}))$$','Interpreter','Latex')
ylabel(app.UIAxesMPELQ,'Power (dB)')
axis(app.UIAxesMPELQ, 'tight');
title(app.UIAxesMPELQ, '')

 if(verbose==1)
    app.MULTIPATHButtonMPELQ.Text = 'MULTIPATH';
    app.MULTIPATHButtonMPELQ.Icon = 'tick.png';
    app.ALLSIGNALSButtonMPELQ.Icon = '';
    app.JAMMINGButtonMPELQ.Icon = '';
    app.SPOOFINGButtonMPELQ.Icon = '';    
    app.CLEANButtonMPELQ.Icon = '';
    close(h);
 end
        end

        % Button pushed function: SPOOFINGButtonMPELQ
        function SPOOFINGButtonMPELQPushed(app, event)
%% Top-level Monte-Carlo Script for simulating PD-ML Observables
% Author: Jason Gross with slight mods by Cagri Kilic

clc;close all;
% set some simulation defaults
s.PA = app.PAEditFieldMPELQ.Value;
s.etadB = app.EtadEditFieldMPELQ.Value;
s.N0 = app.N0EditFieldMPELQ.Value;
s.Ms = app.MsEditFieldMPELQ.Value;
s.eml = app.EmlEditFieldMPELQ.Value;
s.Ta = app.TaEditFieldMPELQ.Value;
s.WFE = app.WFEEditFieldMPELQ.Value;
s.WFEbeta = app.WFEbetaEditFieldMPELQ.Value;
s.Pbeta = app.PbetaEditFieldMPELQ.Value;
s.Tc = app.TcEditFieldMPELQ.Value;
s.sigmaP = app.sigmaPEditFieldMPELQ.Value;
s.taud = app.taudEditFieldMPELQ.Value;
s.trueDelaySigma=app.TrueDelayEditFieldMPELQ.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
% multipath estimator struct
%------------------------------------------------
s.nTaps=app.nTapsEditFieldMPELQ.Value; %select nTaps number as 5,7,15 or 41
%------------------------------------------------
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));


% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end


Np=app.TrialsEditFieldMPELQ.Value;% number of trials of theta
Nm=app.VectorEditFieldMPELQ.Value;  % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution
pPriorClean=app.PRIODCleanEditFieldMPELQ.Value;
pPriorMultipath=app.PRIODMultipathEditFieldMPELQ.Value;
pPriorSpoof=app.PRIODSpoofingEditFieldMPELQ.Value;
pPriorJam=app.PRIODJammingEditFieldMPELQ.Value;

verbose=1;
if (verbose==1)
    h=waitbar(0,'Simulation Progress');
    app.SPOOFINGButtonMPELQ.Icon = 'loading.gif';
    app.SPOOFINGButtonMPELQ.Text = 'Processing...';
end
for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
    
end
close(h);
clear h;

if (verbose==1)
    h=waitbar(0,'Plot Progress');
    app.SPOOFINGButtonMPELQ.Icon = 'loading.gif';
    app.SPOOFINGButtonMPELQ.Text = 'Plotting...';
end
% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


% clear h
% delete h
% save -v7.3 mpeIQMonteCarloall.mat
cla(app.UIAxesMPELQ, 'reset')

hold(app.UIAxesMPELQ,'on')
semilogx(app.UIAxesMPELQ,sp1X,spP,'r.','MarkerSize', 5);

xlabel(app.UIAxesMPELQ,'$$RSS(\frac{1}{a_0} exp(-j\theta_0)(\xi_k - \hat{\xi_0}))$$','Interpreter','Latex')
ylabel(app.UIAxesMPELQ,'Power (dB)')
axis(app.UIAxesMPELQ, 'tight');
title(app.UIAxesMPELQ, '')

 if(verbose==1)
    app.SPOOFINGButtonMPELQ.Text = 'SPOOFING';
    app.SPOOFINGButtonMPELQ.Icon = 'tick.png';
    app.MULTIPATHButtonMPELQ.Icon = '';   
    app.CLEANButtonMPELQ.Icon = '';
    app.ALLSIGNALSButtonMPELQ.Icon = '';
    app.JAMMINGButtonMPELQ.Icon = '';
    close(h);
 end
        end

        % Button pushed function: JAMMINGButtonMPELQ
        function JAMMINGButtonMPELQPushed(app, event)
%% Top-level Monte-Carlo Script for simulating PD-ML Observables
% Author: Jason Gross with slight mods by Cagri Kilic

clc;close all;
% set some simulation defaults
s.PA = app.PAEditFieldMPELQ.Value;
s.etadB = app.EtadEditFieldMPELQ.Value;
s.N0 = app.N0EditFieldMPELQ.Value;
s.Ms = app.MsEditFieldMPELQ.Value;
s.eml = app.EmlEditFieldMPELQ.Value;
s.Ta = app.TaEditFieldMPELQ.Value;
s.WFE = app.WFEEditFieldMPELQ.Value;
s.WFEbeta = app.WFEbetaEditFieldMPELQ.Value;
s.Pbeta = app.PbetaEditFieldMPELQ.Value;
s.Tc = app.TcEditFieldMPELQ.Value;
s.sigmaP = app.sigmaPEditFieldMPELQ.Value;
s.taud = app.taudEditFieldMPELQ.Value;
s.trueDelaySigma=app.TrueDelayEditFieldMPELQ.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
% multipath estimator struct
%------------------------------------------------
s.nTaps=app.nTapsEditFieldMPELQ.Value; %select nTaps number as 5,7,15 or 41
%------------------------------------------------
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));


% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end


Np=app.TrialsEditFieldMPELQ.Value;% number of trials of theta
Nm=app.VectorEditFieldMPELQ.Value;  % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution
pPriorClean=app.PRIODCleanEditFieldMPELQ.Value;
pPriorMultipath=app.PRIODMultipathEditFieldMPELQ.Value;
pPriorSpoof=app.PRIODSpoofingEditFieldMPELQ.Value;
pPriorJam=app.PRIODJammingEditFieldMPELQ.Value;

verbose=1;
if (verbose==1)
    h=waitbar(0,'Simulation Progress');
    app.JAMMINGButtonMPELQ.Icon = 'loading.gif';
    app.JAMMINGButtonMPELQ.Text = 'Processing...';
end
for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
    
end
close(h);
clear h;

if (verbose==1)
    h=waitbar(0,'Plot Progress');
    app.JAMMINGButtonMPELQ.Icon = 'loading.gif';
    app.JAMMINGButtonMPELQ.Text = 'Plotting...';
end
% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


% clear h
% delete h
% save -v7.3 mpeIQMonteCarloall.mat
cla(app.UIAxesMPELQ, 'reset')

hold(app.UIAxesMPELQ,'on')
semilogx(app.UIAxesMPELQ,jm1X,jmP,'b.','MarkerSize', 5);

xlabel(app.UIAxesMPELQ,'$$RSS(\frac{1}{a_0} exp(-j\theta_0)(\xi_k - \hat{\xi_0}))$$','Interpreter','Latex')
ylabel(app.UIAxesMPELQ,'Power (dB)')
axis(app.UIAxesMPELQ, 'tight');
title(app.UIAxesMPELQ, '')

 if(verbose==1)
    app.JAMMINGButtonMPELQ.Text = 'JAMMING';
    app.JAMMINGButtonMPELQ.Icon = 'tick.png';
    app.SPOOFINGButtonMPELQ.Icon = '';
    app.MULTIPATHButtonMPELQ.Icon = ''; 
    app.CLEANButtonMPELQ.Icon = '';
    app.ALLSIGNALSButtonMPELQ.Icon = '';
    close(h);
 end
        end

        % Button pushed function: MPELQButton
        function MPELQButtonPushed(app, event)
%% Top-level Monte-Carlo Script for simulating PD-ML Observables
% Author: Jason Gross with slight mods by Cagri Kilic

clc;close all;
% set some simulation defaults
s.PA = app.PAEditFieldMPELQ.Value;
s.etadB = app.EtadEditFieldMPELQ.Value;
s.N0 = app.N0EditFieldMPELQ.Value;
s.Ms = app.MsEditFieldMPELQ.Value;
s.eml = app.EmlEditFieldMPELQ.Value;
s.Ta = app.TaEditFieldMPELQ.Value;
s.WFE = app.WFEEditFieldMPELQ.Value;
s.WFEbeta = app.WFEbetaEditFieldMPELQ.Value;
s.Pbeta = app.PbetaEditFieldMPELQ.Value;
s.Tc = app.TcEditFieldMPELQ.Value;
s.sigmaP = app.sigmaPEditFieldMPELQ.Value;
s.taud = app.taudEditFieldMPELQ.Value;
s.trueDelaySigma=app.TrueDelayEditFieldMPELQ.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
% multipath estimator struct
%------------------------------------------------
s.nTaps=app.nTapsEditFieldMPELQ.Value; %select nTaps number as 5,7,15 or 41
%------------------------------------------------
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));


% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end


Np=app.TrialsEditFieldMPELQ.Value;% number of trials of theta
Nm=app.VectorEditFieldMPELQ.Value;  % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution
pPriorClean=app.PRIODCleanEditFieldMPELQ.Value;
pPriorMultipath=app.PRIODMultipathEditFieldMPELQ.Value;
pPriorSpoof=app.PRIODSpoofingEditFieldMPELQ.Value;
pPriorJam=app.PRIODJammingEditFieldMPELQ.Value;

verbose=1;
if (verbose==1)
    h=waitbar(0,'Generating MPELQ MonteCarlo Data');
    app.MPELQButton.Icon = 'loading.gif';
    app.MPELQButton.Text = 'PROCESSING DATA...';
end


for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
    
end

clear h;

if (verbose==1)
    h=waitbar(0,'Updating MPELQ Data');
end
% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


% clear h
% delete h
save -v7.3 mpeIQMonteCarloall.mat


app.MPELQButton.Text = 'Update MPELQ Data';
app.MPELQButton.Icon = 'update.png';
close(h);

        end

        % Button pushed function: FIGURES_PDML
        function FIGURES_PDMLButtonPushed(app, event)
% decisionForMinimumBayesianRiskBoundary.m create the
% PDML defense decision regions
% Author: Jason Gross with slight mods by Cagri Kilic
% -------------------------------------------------------------------------
% NOTE: this script assumes that the Matlab workspace
% is populated with mpeIQMonteCarlo.m if not please uncomment below!
% -------------------------------------------------------------------------
% -------------------------------------------------------------------------
% clear all;
load mpeIQMonteCarloall.mat
% -------------------------------------------------------------------------
% -------------------------------------------------------------------------
% configuration variables
% -------------------------------------------------------------------------
nTaps=11;            % select the taps number as 5,7,15 or 41 if you have
% -------------------------------------------------------------------------
% variable cost in theta 2, fixed 1, misclass 0
SAVEFIGS=0;             % switch to save time history of detector optimzation
tau_eml=2/(nTaps-1);    % spacing assumed (in chips) for the symmetric difference
pow=-1.5:.3:20;        % range and resolution of power for which regions are drawn
d1=[01:5:100 101:50:500 550:500:8500];		% range and resolution of symmetric differences for which regions are drawn
[da1R,powerR]=meshgrid(d1,pow);

% assume a reasonable partition based on visual
% inspection of the scatter plot for the initial decision regions

pdmlDecision=zeros(size(da1R));
benignSpoofMat=zeros(size(da1R));

%% 41taps
for i=1:length(pow)
    for j=1:length(d1)
        if (pow(i)<1.1&& d1(j)<30  )
            pdmlDecision(i,j)=1;
        elseif (pow(i)<1.1 && d1(j)>=30 )
            pdmlDecision(i,j)=2;
        elseif (pow(i)>=1.1&& d1(j)<50)
            pdmlDecision(i,j)=4;
        else
            pdmlDecision(i,j)=3;
        end
    end
end
% plot the initial decision regoins
mymap=[0 1 0; 0 0 0; 1 0 0; 0 0 1];

figure()
colormap(mymap);
h=pcolor(log10(d1),pow,pdmlDecision);
set(h, 'EdgeColor', 'none');
%xlabel('Distortion |a1|')
%ylabel('Power (dB)')
set(gca,'FontSize',14)
title('Initial Pincer Decision Partions')
drawnow
%saveas(gcf,'pincerDecinit.png');
%saveas(gcf,'pincerDecinit.eps');
%for a=0:2
a=1;
costType=a;

%initialize variables
dataKeys=zeros(Np,Nm);
powerSimObs=zeros(Np,Nm);
da0a1SimObs=zeros(Np,Nm);
thetas=zeros(Np,Nm);

deltaThetas=zeros(Np,Nm);
deltaTaus=zeros(Np,Nm);
etadBs=zeros(Np,Nm);
for i=1:Np
    for j=1:Nm
        PLinNoisey= 10^(Out(i,j).P/10);
        P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
        p=P_WRT_Nom;
        d=(mpeOut1(i,j).chiSqr);
        
        [c powBinLocation] = min(abs(pow-p));
        [c da0a1BinLocation] = min(abs(d1-d));
        
        % determine unique 1D key within the 2D grid
        dataKeys(i,j)=(da0a1BinLocation)*length(d1)+powBinLocation;
        powerSimObs(i,j)=p;
        da0a1SimObs(i,j)=d;
        % although there is only one unique theta per set of Nm obs
        % storing this way makes keying the data easier
        thetas(i,j)=thetaKeys(i);
        deltaThetas(i,j)=simConfigs(i).Delta_theta;
        deltaTaus(i,j)=simConfigs(i).Delta_tau;
        etadBs(i,j)=simConfigs(i).etadB;
        
    end
end

% go through all the simulated obs & determine if it lies on a decision
% region boundary. if so, evaluate the current cost
% and find all other simulated obs of the same key location.
% determine if the cost of the location would be lower
% if another hypotheses were accepted.
% if so, change the for the location decision accordingly.
% for efficiency, keep track of the keys that have been checked and
% only check each key them once.
% iterate until now more boundaries are changed.

keysAlreadyChecked=[];
changedDecisionIndex=1;
if(SAVEFIGS)
    figSaveStr=sprintf('pincerDec%08d.png',changedDecisionIndex);
    saveas(gcf,figSaveStr);
end

costChange=0;
firstIteration=1;
lastPincerDecision=pdmlDecision;
% unless it is the first optimization iteration, quit optimizing if no
% changes were made in the last iteration.
while(sum(sum(pdmlDecision-lastPincerDecision))~=0 || firstIteration==1 )
    firstIteration=0;
    lastPincerDecision=pdmlDecision;
    for i=1:Np
        for j=1:Nm
            key=dataKeys(i,j);
            % only check each grid key once
            if find(key==keysAlreadyChecked)
                continue
            end
            % determine if on a decision boundary, if not, continue
            onDecisionBoundary=0;
            powLoc=mod(key,length(d1));
            
            da1Loc=floor((key)/length(d1));
            if((da1Loc>1))&& (da1Loc<length(d1)) &&( (powLoc>1)&&(powLoc<length(pow)))
                
                if powLoc<length(pow)
                    if(pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc+1,da1Loc))
                        onDecisionBoundary=1;
                    end
                end
                if powLoc<length(pow) && da1Loc<length(d1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc+1,da1Loc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(da1Loc<length(d1))
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc,da1Loc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc-1,da1Loc))
                        onDecisionBoundary=1;
                    end
                end
                if(da1Loc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc,da1Loc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(da1Loc>1 && powLoc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc-1,da1Loc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc<length(pow) && da1Loc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc+1,da1Loc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1 && da1Loc<length(d1))
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc-1,da1Loc+1))
                        onDecisionBoundary=1;
                    end
                end
            end
            
            if(onDecisionBoundary==0)
                continue
            end
            
            % keep track if this location has already been evaluated
            keysAlreadyChecked=[keysAlreadyChecked key];
            
            % evaluate cost of current decision and the cost of all the other
            % possible decisions for this  grid key location
            decisionOfGridKey=interp2(da1R,powerR,pdmlDecision,...
                da0a1SimObs(i,j),powerSimObs(i,j),'nearest');
            
            thetasInGridKey=thetas(dataKeys==key);
            etadBsinGridKey=etadBs(dataKeys==key);
            deltaTauIAsInGridKey=deltaTaus(dataKeys==key);
            deltaThetaIAsInGridKey=deltaThetas(dataKeys==key);
            
            currentCost=0;
            otherDecisions=find([1,2,3,4]~=decisionOfGridKey);
            otherDecisionCosts=[0,0,0];
            powLoc=mod(key,length(d1));
            da1Loc=floor((key)/length(d1));
            
            
            for k=1:length(thetasInGridKey)
                
                currentCost=currentCost+ pdmlCostFunctionSingle(decisionOfGridKey,...
                    thetasInGridKey(k)+1, etadBsinGridKey(k) ,deltaTauIAsInGridKey(k), ...
                    deltaThetaIAsInGridKey(k),tau_eml,costType);
                %                  benignSpoofMat(powLoc,da1Loc)=benignSpoof(decisionOfGridKey,...
                %                     thetasInGridKey(k)+1, etadBsinGridKey(k) ,deltaTauIAsInGridKey(k), ...
                %                     deltaThetaIAsInGridKey(k),tau_eml,costType);
                
                for l=1:length(otherDecisions)
                    otherDecisionCosts(l)=otherDecisionCosts(l)+ pdmlCostFunctionSingle(otherDecisions(l),...
                        thetasInGridKey(k)+1,etadBsinGridKey(k) ,...
                        deltaTauIAsInGridKey(k), deltaThetaIAsInGridKey(k),tau_eml,costType);
                end
            end
            
            % determine if cost can be reduced, if so, accept lowest cost
            if(sum(otherDecisionCosts<currentCost))
                newDecision=otherDecisions(otherDecisionCosts==min(otherDecisionCosts));
                
                % 2D grid location from key value
                powLoc=mod(key,length(d1));
                da1Loc=floor((key)/length(d1));
                
                
                disp([da1Loc powLoc])
                pdmlDecision(powLoc,da1Loc)=newDecision(1);
                changedDecisionIndex=changedDecisionIndex+1;
                
                % save the cost improvement history (for plotting)
                costChange(changedDecisionIndex)=costChange(changedDecisionIndex-1)+...
                    (currentCost-min(otherDecisionCosts));
            end
        end
    end
end
[M,N]=size(pdmlDecision);
for i=2:M-1
    for j=2:N-1
        
        decision=pdmlDecision(i,j);
        boundaryDecision=pdmlDecision(i,j+1);
        boundaryCount=0;
        if ((pdmlDecision(i,j-1)==boundaryDecision))
            boundaryCount= boundaryCount+1;
        end
        if ((pdmlDecision(i-1,j)==boundaryDecision))
            boundaryCount= boundaryCount+1;
        end
        if ((pdmlDecision(i+1,j)==boundaryDecision))
            boundaryCount= boundaryCount+1;
        end
        if (decision~=boundaryDecision)&&(boundaryCount>0)
            disp('Found Island')
            pdmlDecision(i,j)=boundaryDecision;
        end
    end
end

figure()
colormap(mymap);
h=pcolor(log10(d1),pow,pdmlDecision+benignSpoofMat);
set(h, 'EdgeColor', 'none');
%xlabel('Distortion |a1|')
%ylabel('Power (dB)')
set(gca,'FontSize',14)

figSaveStrpng=sprintf('pincerDec%d.png',a);
saveas(gcf,figSaveStrpng);
figSaveStrfig=sprintf('pincerDec%d.fig',a);
saveas(gcf,figSaveStrfig);
figSaveStrfig=sprintf('pincerDec%d.eps',a);
saveas(gcf,figSaveStrfig);

if costType==0
    save detectionCF0a1.mat d1 pow pdmlDecision
elseif costType==1
    save detectionCF1a1.mat d1 pow pdmlDecision
elseif costType==2
    save detectionCF2a1.mat d1 pow pdmlDecision
end
%end
            
        end

        % Button pushed function: INITIAL_PDML
        function INITIAL_PDMLButtonPushed(app, event)
% decisionForMinimumBayesianRiskBoundary.m create the
% PDML defense decision regions
% Author: Jason Gross with slight mods by Cagri Kilic
% -------------------------------------------------------------------------
% NOTE: this script assumes that the Matlab workspace
% is populated with mpeIQMonteCarlo.m if not please uncomment below!
% -------------------------------------------------------------------------
% -------------------------------------------------------------------------
% clear all;
load mpeIQMonteCarloall.mat
% -------------------------------------------------------------------------
% -------------------------------------------------------------------------
% configuration variables
% -------------------------------------------------------------------------
nTaps=11;            % select the taps number as 5,7,15 or 41 if you have
% -------------------------------------------------------------------------
% variable cost in theta 2, fixed 1, misclass 0
SAVEFIGS=0;             % switch to save time history of detector optimzation
tau_eml=2/(nTaps-1);    % spacing assumed (in chips) for the symmetric difference
pow=-1.5:.3:20;        % range and resolution of power for which regions are drawn
d1=[01:5:100 101:50:500 550:500:8500];		% range and resolution of symmetric differences for which regions are drawn
[da1R,powerR]=meshgrid(d1,pow);

% assume a reasonable partition based on visual
% inspection of the scatter plot for the initial decision regions

pdmlDecision=zeros(size(da1R));
benignSpoofMat=zeros(size(da1R));

%% 41taps
for i=1:length(pow)
    for j=1:length(d1)
        if (pow(i)<1.1&& d1(j)<30  )
            pdmlDecision(i,j)=1;
        elseif (pow(i)<1.1 && d1(j)>=30 )
            pdmlDecision(i,j)=2;
        elseif (pow(i)>=1.1&& d1(j)<50)
            pdmlDecision(i,j)=4;
        else
            pdmlDecision(i,j)=3;
        end
    end
end
% plot the initial decision regoins
mymap=[0 1 0; 0 0 0; 1 0 0; 0 0 1];


colormap(app.UIAxesPDML_1, mymap);
cmap = pcolor(app.UIAxesPDML_1, log10(d1),pow,pdmlDecision);
cmap.EdgeColor = 'none';
%xlabel('Distortion |a1|')
%ylabel('Power (dB)')
set(gca,'FontSize',14)
title(app.UIAxesPDML_1, 'Initial Pincer Decision Partions')
drawnow
%saveas(gcf,'pincerDecinit.png');
%saveas(gcf,'pincerDecinit.eps');
%for a=0:2
a=1;
costType=a;

%initialize variables
dataKeys=zeros(Np,Nm);
powerSimObs=zeros(Np,Nm);
da0a1SimObs=zeros(Np,Nm);
thetas=zeros(Np,Nm);

deltaThetas=zeros(Np,Nm);
deltaTaus=zeros(Np,Nm);
etadBs=zeros(Np,Nm);
for i=1:Np
    for j=1:Nm
        PLinNoisey= 10^(Out(i,j).P/10);
        P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
        p=P_WRT_Nom;
        d=(mpeOut1(i,j).chiSqr);
        
        [c powBinLocation] = min(abs(pow-p));
        [c da0a1BinLocation] = min(abs(d1-d));
        
        % determine unique 1D key within the 2D grid
        dataKeys(i,j)=(da0a1BinLocation)*length(d1)+powBinLocation;
        powerSimObs(i,j)=p;
        da0a1SimObs(i,j)=d;
        % although there is only one unique theta per set of Nm obs
        % storing this way makes keying the data easier
        thetas(i,j)=thetaKeys(i);
        deltaThetas(i,j)=simConfigs(i).Delta_theta;
        deltaTaus(i,j)=simConfigs(i).Delta_tau;
        etadBs(i,j)=simConfigs(i).etadB;
        
    end
end

% go through all the simulated obs & determine if it lies on a decision
% region boundary. if so, evaluate the current cost
% and find all other simulated obs of the same key location.
% determine if the cost of the location would be lower
% if another hypotheses were accepted.
% if so, change the for the location decision accordingly.
% for efficiency, keep track of the keys that have been checked and
% only check each key them once.
% iterate until now more boundaries are changed.

keysAlreadyChecked=[];
changedDecisionIndex=1;
if(SAVEFIGS)
    figSaveStr=sprintf('pincerDec%08d.png',changedDecisionIndex);
    saveas(gcf,figSaveStr);
end

costChange=0;
firstIteration=1;
lastPincerDecision=pdmlDecision;
% unless it is the first optimization iteration, quit optimizing if no
% changes were made in the last iteration.
while(sum(sum(pdmlDecision-lastPincerDecision))~=0 || firstIteration==1 )
    firstIteration=0;
    lastPincerDecision=pdmlDecision;
    for i=1:Np
        for j=1:Nm
            key=dataKeys(i,j);
            % only check each grid key once
            if find(key==keysAlreadyChecked)
                continue
            end
            % determine if on a decision boundary, if not, continue
            onDecisionBoundary=0;
            powLoc=mod(key,length(d1));
            
            da1Loc=floor((key)/length(d1));
            if((da1Loc>1))&& (da1Loc<length(d1)) &&( (powLoc>1)&&(powLoc<length(pow)))
                
                if powLoc<length(pow)
                    if(pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc+1,da1Loc))
                        onDecisionBoundary=1;
                    end
                end
                if powLoc<length(pow) && da1Loc<length(d1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc+1,da1Loc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(da1Loc<length(d1))
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc,da1Loc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc-1,da1Loc))
                        onDecisionBoundary=1;
                    end
                end
                if(da1Loc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc,da1Loc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(da1Loc>1 && powLoc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc-1,da1Loc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc<length(pow) && da1Loc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc+1,da1Loc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1 && da1Loc<length(d1))
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc-1,da1Loc+1))
                        onDecisionBoundary=1;
                    end
                end
            end
            
            if(onDecisionBoundary==0)
                continue
            end
            
            % keep track if this location has already been evaluated
            keysAlreadyChecked=[keysAlreadyChecked key];
            
            % evaluate cost of current decision and the cost of all the other
            % possible decisions for this  grid key location
            decisionOfGridKey=interp2(da1R,powerR,pdmlDecision,...
                da0a1SimObs(i,j),powerSimObs(i,j),'nearest');
            
            thetasInGridKey=thetas(dataKeys==key);
            etadBsinGridKey=etadBs(dataKeys==key);
            deltaTauIAsInGridKey=deltaTaus(dataKeys==key);
            deltaThetaIAsInGridKey=deltaThetas(dataKeys==key);
            
            currentCost=0;
            otherDecisions=find([1,2,3,4]~=decisionOfGridKey);
            otherDecisionCosts=[0,0,0];
            powLoc=mod(key,length(d1));
            da1Loc=floor((key)/length(d1));
            
            
            for k=1:length(thetasInGridKey)
                
                currentCost=currentCost+ pdmlCostFunctionSingle(decisionOfGridKey,...
                    thetasInGridKey(k)+1, etadBsinGridKey(k) ,deltaTauIAsInGridKey(k), ...
                    deltaThetaIAsInGridKey(k),tau_eml,costType);
                %                  benignSpoofMat(powLoc,da1Loc)=benignSpoof(decisionOfGridKey,...
                %                     thetasInGridKey(k)+1, etadBsinGridKey(k) ,deltaTauIAsInGridKey(k), ...
                %                     deltaThetaIAsInGridKey(k),tau_eml,costType);
                
                for l=1:length(otherDecisions)
                    otherDecisionCosts(l)=otherDecisionCosts(l)+ pdmlCostFunctionSingle(otherDecisions(l),...
                        thetasInGridKey(k)+1,etadBsinGridKey(k) ,...
                        deltaTauIAsInGridKey(k), deltaThetaIAsInGridKey(k),tau_eml,costType);
                end
            end
            
            % determine if cost can be reduced, if so, accept lowest cost
            if(sum(otherDecisionCosts<currentCost))
                newDecision=otherDecisions(otherDecisionCosts==min(otherDecisionCosts));
                
                % 2D grid location from key value
                powLoc=mod(key,length(d1));
                da1Loc=floor((key)/length(d1));
                
                
                disp([da1Loc powLoc])
                pdmlDecision(powLoc,da1Loc)=newDecision(1);
                changedDecisionIndex=changedDecisionIndex+1;
                
                % save the cost improvement history (for plotting)
                costChange(changedDecisionIndex)=costChange(changedDecisionIndex-1)+...
                    (currentCost-min(otherDecisionCosts));
            end
        end
    end
end
[M,N]=size(pdmlDecision);
for i=2:M-1
    for j=2:N-1
        
        decision=pdmlDecision(i,j);
        boundaryDecision=pdmlDecision(i,j+1);
        boundaryCount=0;
        if ((pdmlDecision(i,j-1)==boundaryDecision))
            boundaryCount= boundaryCount+1;
        end
        if ((pdmlDecision(i-1,j)==boundaryDecision))
            boundaryCount= boundaryCount+1;
        end
        if ((pdmlDecision(i+1,j)==boundaryDecision))
            boundaryCount= boundaryCount+1;
        end
        if (decision~=boundaryDecision)&&(boundaryCount>0)
            disp('Found Island')
            pdmlDecision(i,j)=boundaryDecision;
        end
    end
end

figure()
colormap(mymap);
h=pcolor(log10(d1),pow,pdmlDecision+benignSpoofMat);
set(h, 'EdgeColor', 'none');
%xlabel('Distortion |a1|')
%ylabel('Power (dB)')
set(gca,'FontSize',14)

figSaveStrpng=sprintf('pincerDec%d.png',a);
saveas(gcf,figSaveStrpng);
figSaveStrfig=sprintf('pincerDec%d.fig',a);
saveas(gcf,figSaveStrfig);
figSaveStrfig=sprintf('pincerDec%d.eps',a);
saveas(gcf,figSaveStrfig);

if costType==0
    save detectionCF0a1.mat d1 pow pdmlDecision
elseif costType==1
    save detectionCF1a1.mat d1 pow pdmlDecision
elseif costType==2
    save detectionCF2a1.mat d1 pow pdmlDecision
end
%end
            
        end

        % Button pushed function: PSD
        function PSDPushed(app, event)
%% Top-level Monte-Carlo Script for simulating PD-ML Observables
% Author: Jason Gross with slight mods by Cagri Kilic

clc;close all;
% set some simulation defaults
s.PA = app.PAEditFieldMPELQ.Value;
s.etadB = app.EtadEditFieldMPELQ.Value;
s.N0 = app.N0EditFieldMPELQ.Value;
s.Ms = app.MsEditFieldMPELQ.Value;
s.eml = app.EmlEditFieldMPELQ.Value;
s.Ta = app.TaEditFieldMPELQ.Value;
s.WFE = app.WFEEditFieldMPELQ.Value;
s.WFEbeta = app.WFEbetaEditFieldMPELQ.Value;
s.Pbeta = app.PbetaEditFieldMPELQ.Value;
s.Tc = app.TcEditFieldMPELQ.Value;
s.sigmaP = app.sigmaPEditFieldMPELQ.Value;
s.taud = app.taudEditFieldMPELQ.Value;
s.trueDelaySigma=app.TrueDelayEditFieldMPELQ.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
% multipath estimator struct
%------------------------------------------------
s.nTaps=app.nTapsEditFieldMPELQ.Value; %select nTaps number as 5,7,15 or 41
%------------------------------------------------
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));


% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end

N = app.NoofMCRunsEpoch.Value; % number of monte carlo runs at every epoch
Np=N;% number of trials of theta
Nm=app.VectorEditFieldMPELQ.Value;  % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution
pPriorClean=app.PRIODCleanEditFieldMPELQ.Value;
pPriorMultipath=app.PRIODMultipathEditFieldMPELQ.Value;
pPriorSpoof=app.PRIODSpoofingEditFieldMPELQ.Value;
pPriorJam=app.PRIODJammingEditFieldMPELQ.Value;

verbose=1;
if (verbose==1)
    h=waitbar(0,'Simulation Progress');
    app.PSD.Icon = 'loading.gif';
    app.PSD.Text = 'Processing...';
    
end
for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
    
end
close(h);
clear h;

if (verbose==1)
    h=waitbar(0,'Plot Progress');
    app.ALLSIGNALSButtonMPELQ.Icon = 'loading.gif';
    app.ALLSIGNALSButtonMPELQ.Text = 'Plotting Results...';
end
% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


% clear h
% delete h
% save -v7.3 mpeIQMonteCarloall.mat
cla(app.UIAxesMESHEDsmall, 'reset')

semilogx(app.UIAxesMESHEDsmall,sp1X,spP,'r.','MarkerSize', 1);
hold(app.UIAxesMESHEDsmall,'on')
semilogx(app.UIAxesMESHEDsmall,jm1X,jmP,'b.','MarkerSize', 1);
semilogx(app.UIAxesMESHEDsmall,mp1X,mpP,'k.','MarkerSize', 1);
semilogx(app.UIAxesMESHEDsmall,cd1X,cdP,'g.','MarkerSize', 1);


 if(verbose==1)
    app.PSD.Text = 'MESHED SPOOF DETECTION';
    app.PSD.Icon = 'tick.png';
    app.PMD.Icon = '';
    app.COMBINED.Icon = '';    
    close(h);

 end
            
% ///////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////
            
%%Simulation study combining power and distortion measurements for spoofing
%%detection
%   
%   Computes the detection power for detection tests using the power and
%   distortion metrics. Follows algorithms described in
%   
%   Rothmaier, F., Chen, Y., Lo, S., & Walter, T. (2021). A Framework for 
%       GNSS Spoofing Detection through Combinations of Metrics. IEEE 
%       Transactions on Aerospace and Electronic Systems. 
%       https://doi.org/10.1109/TAES.2021.3082673
%   
%   The simulation is described and analyzed in
%   Rothmaier, F., Lo, S., Phelts, E., & Walter, T. (2021). GNSS Spoofing 
%       Detection through Metric Combinations: Calibration and Application 
%       of a general Framework. Proceedings of the 34th International 
%       Technical Meeting of the Satellite Division of the Institute of 
%       Navigation, ION GNSS+ 2021.
%   
%   Please cite the reference if using this material.
%   
%   This code and the repository it is in is provided free of charge under
%   an MIT license. For questions please contact Fabian Rothmaier
%   fabianr@stanford.edu.
%   
%   Written by Fabian Rothmaier, Stanford University, 2021


%% prepare workspace

close all;

rng(1); % for reproducability

%% set simulation parameters

CN0nom = app.NominalSignalPower.Value; % nominal signal strength in dB-Hz

sigP = app.SDforPowermetric.Value; % standard deviation of power metric in dB

ELdist = app.EarlyLateCollerator.Value; % early/late correlator positions in chips from prompt

P_FA = app.FalseAlertProbability.Value; % false alert probability

deltaCN0s = 1:app.SpooferPowerAdvantage.Value; % different spoofer power advantages in dB


offsets = 0:0.01:(1+ELdist+0.01); % offsets of spoofer's triangle in code chips

dx = 0.001;
x = -1-min(offsets):dx:1+max(offsets); % code chip range around authentic peak
xTI = round(ELdist/dx) * [-1 0 1]; % correlation tab spacing in units of x

% calculate height of nominal signal peak at each epoch
nomPeak = expectedPeaksize(CN0nom);
nomCorrFun = nomPeak .* Rcorr(x'); % nominal correlation triangle

%% run simulation during lift-off
deltaMetric = zeros(length(deltaCN0s), length(offsets));
peakSize = zeros(length(deltaCN0s), length(offsets));

[meanLLD, maxLLD, meanLLP, maxLLP] = deal(zeros(1, length(deltaCN0s)));

for deltaI = 1:length(deltaCN0s)

    
    deltaCN0 = deltaCN0s(deltaI); % spoofer power advantage

    %% set peak sizes
    CN0spo = CN0nom + deltaCN0;

    spoPeak = expectedPeaksize(CN0spo);
    
    % compute spoofed correlation function
    spoCorrFun = spoPeak .* Rcorr(x' - offsets);
    
    % sum with nominal values
    sumCorrFun = nomCorrFun  + spoCorrFun;

    %% compute nominal peak, spoofed peak
    
    peakIs = zeros(size(offsets));
    
    deltaMetric = zeros(N, length(offsets));
    measCN0 = zeros(N, length(offsets));
    
    for ii = 1:length(offsets)
    
        % find peak, assume perfect tracking
        [peakSize(deltaI, ii), peakIs(ii)] = max(sumCorrFun(:, ii));

        % run a Monte Carlo for Delta metric measurement
        corrTapStd = deltaSigmaVal(expectedCN0(peakSize(deltaI, ii)))/sqrt(2);
        corrTapNoise = corrTapStd*randn(N, length(xTI)); % is normalized by peaksize
        corrTapVals = sumCorrFun(peakIs(ii)+xTI, ii)' + peakSize(deltaI, ii)* corrTapNoise;
        
        deltaMetric(:, ii) = (corrTapVals(:, 1) - corrTapVals(:, 3)) ./ corrTapVals(:, 2);
        
        % get measured CN0 (mostly unaffected by noise on correlator taps)
        measCN0(:, ii) = expectedCN0(corrTapVals(:, 2));
        
    end
    
    % calculate log Lambda of the delta metric
    sigDelta = deltaSigmaVal(measCN0);
    logLaD = (deltaMetric ./ sigDelta).^2;
    
    % calculate log Lambda of the power metric, now do Monte Carlo for
    % noise on power metric (excluded before for comp. efficiency, does not
    % affect delta metric)
    % This includes extra noise on power measurement.
    logLaP = ((measCN0 + sigP*randn(N, length(offsets)) - CN0nom) / sigP).^2;
    
    % calculate alarms
    alarm.D = sum(logLaD > chi2inv(1-P_FA, 1), 1);
    alarm.P = sum(logLaP > chi2inv(1-P_FA, 1), 1);
    alarm.Dor = sum(logLaD > chi2inv(1-P_FA/2, 1), 1);
    alarm.Por = sum(logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.OR = sum(logLaD > chi2inv(1-P_FA/2, 1) | logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.Dand = sum(logLaD > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.Pand = sum(logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.AND = sum(logLaD > chi2inv(1-sqrt(P_FA), 1) & logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.joint = sum(logLaP + logLaD > chi2inv(1-P_FA, 2), 1);
    
    for metric = fields(alarm)'      
        % minimum P_MD at best epoch
        P_MDmin.(metric{1})(deltaI) = 1 - max(alarm.(metric{1})) / N;
        % average P_MD across all epochs
        P_MDmean.(metric{1})(deltaI) = 1 - mean(alarm.(metric{1})) / N;
    end

end


%% plot results
plt = PlotLatexStyle;

% compute detection with OR, AND, joint metric

% plot average P_MD in %
cla(app.UIAxesMESHED, 'reset')
hold(app.UIAxesMESHED,'on')
grid(app.UIAxesMESHED, "off");

p = (P_MDmean.P);
d = (P_MDmean.D);
a = (P_MDmean.AND);
o = (P_MDmean.OR);
j = (P_MDmean.joint);

pp = p(deltaCN0s);
dd = d(deltaCN0s);
aa = a(deltaCN0s);
oo = o(deltaCN0s);
jj = j(deltaCN0s);
meshedautoswicher = max(jj,max(oo,max(aa,max(pp,dd))));

x = abs(deltaCN0s);
y =  offsets*N;
[xx,yy] = meshgrid(x,y);

px = ((xx.*pp-1)/deltaI);
dx = ((xx.*dd-1)/deltaI);
ax = ((xx.*aa-1)/deltaI);
ox = ((xx.*oo-1)/deltaI);
jx = ((xx.*jj-1)/deltaI);
cx = ((xx.*meshedautoswicher-1)/deltaI);


surfc(app.UIAxesMESHED,xx,yy,1-px)
surfc(app.UIAxesMESHED,xx,yy,1-dx)
surfc(app.UIAxesMESHED,xx,yy,1-ax)
surfc(app.UIAxesMESHED,xx,yy,1-ox)
surfc(app.UIAxesMESHED,xx,yy,1-jx)
surfc(app.UIAxesMESHED,xx,yy,1-cx)


view(app.UIAxesMESHED,[20,20])
colormap (app.UIAxesMESHED,"jet(500)")
shading(app.UIAxesMESHED, "interp")
cb = colorbar(app.UIAxesMESHED);
cb.Title
cb.Title.String = "Detection Probability";

xlabel(app.UIAxesMESHED, 'Spoofer power advantage (dB)', plt.axisLabelArgs{:})
ylabel(app.UIAxesMESHED, 'Samples', plt.axisLabelArgs{:})
zlabel(app.UIAxesMESHED, 'Spoof Detection Probability', plt.axisLabelArgs{:})
plt.latexLegend(app.UIAxesMESHED, 'MESHED', 'INDIVIDUALS')


%% helper functions
function ps = expectedPeaksize(CN0, N0)
%Calculate the theoretical peak size based on CN0, N0.

if nargin < 2
    N0 = -203;
end

ps = sqrt(10.^((CN0 + N0)/10))*10^12 .* 500;

end


function eCN0 = expectedCN0(peaksize, N0)
%Compute the expected CN0 as a function of correlation peak size.

if nargin < 2
    N0 = -203;
end

% compute the expected C/N0
eCN0 = 10 * log10((2*peaksize /1e15).^2) - N0;

end                   
        end

        % Value changed function: FalseAlertProbability
        function FalseAlertProbabilityValueChanged(app, event)
            app.FalseAlertProbability.Value;
        end

        % Value changed function: NoofMCRunsEpoch
        function NoofMCRunsEpochValueChanged(app, event)
            app.NoofMCRunsEpoch.Value;
        end

        % Value changed function: NominalSignalPower
        function NominalSignalPowerValueChanged(app, event)
            app.NominalSignalPower.Value;
        end

        % Value changed function: SDforPowermetric
        function SDforPowermetricValueChanged(app, event)
            app.SDforPowermetric.Value;
        end

        % Value changed function: EarlyLateCollerator
        function EarlyLateColleratorValueChanged(app, event)
            app.EarlyLateCollerator.Value;
        end

        % Button pushed function: ClearButtonGLRT
        function ClearButtonGLRTPushed(app, event)
            cla(app.UIAxesMESHED, 'reset')
            cla(app.UIAxesMESHEDsmall, 'reset')
            app.PercentValue.Value = 0;
        end

        % Button pushed function: PMD
        function PMDButtonPushed(app, event)
%% Top-level Monte-Carlo Script for simulating PD-ML Observables
% Author: Jason Gross with slight mods by Cagri Kilic

clc;close all;
% set some simulation defaults
s.PA = app.PAEditFieldMPELQ.Value;
s.etadB = app.EtadEditFieldMPELQ.Value;
s.N0 = app.N0EditFieldMPELQ.Value;
s.Ms = app.MsEditFieldMPELQ.Value;
s.eml = app.EmlEditFieldMPELQ.Value;
s.Ta = app.TaEditFieldMPELQ.Value;
s.WFE = app.WFEEditFieldMPELQ.Value;
s.WFEbeta = app.WFEbetaEditFieldMPELQ.Value;
s.Pbeta = app.PbetaEditFieldMPELQ.Value;
s.Tc = app.TcEditFieldMPELQ.Value;
s.sigmaP = app.sigmaPEditFieldMPELQ.Value;
s.taud = app.taudEditFieldMPELQ.Value;
s.trueDelaySigma=app.TrueDelayEditFieldMPELQ.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
% multipath estimator struct
%------------------------------------------------
s.nTaps=app.nTapsEditFieldMPELQ.Value; %select nTaps number as 5,7,15 or 41
%------------------------------------------------
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));


% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end

N = app.NoofMCRunsEpoch.Value; % number of monte carlo runs at every epoch
Np=N;% number of trials of theta
Nm=app.VectorEditFieldMPELQ.Value;  % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution
pPriorClean=app.PRIODCleanEditFieldMPELQ.Value;
pPriorMultipath=app.PRIODMultipathEditFieldMPELQ.Value;
pPriorSpoof=app.PRIODSpoofingEditFieldMPELQ.Value;
pPriorJam=app.PRIODJammingEditFieldMPELQ.Value;

verbose=1;
if (verbose==1)
    h=waitbar(0,'Simulation Progress');
    app.PMD.Icon = 'loading.gif';
    app.PMD.Text = 'Processing...';
    
end
for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
    
end
close(h);
clear h;

if (verbose==1)
    h=waitbar(0,'Plot Progress');
    pp.ALLSIGNALSButtonMPELQ.Icon = 'loading.gif';
    app.ALLSIGNALSButtonMPELQ.Text = 'Plotting Results...';
end
% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


% clear h
% delete h
% save -v7.3 mpeIQMonteCarloall.mat
cla(app.UIAxesMESHEDsmall, 'reset')

semilogx(app.UIAxesMESHEDsmall,sp1X,spP,'r.','MarkerSize', 1);
hold(app.UIAxesMESHEDsmall,'on')
semilogx(app.UIAxesMESHEDsmall,jm1X,jmP,'b.','MarkerSize', 1);
semilogx(app.UIAxesMESHEDsmall,mp1X,mpP,'k.','MarkerSize', 1);
semilogx(app.UIAxesMESHEDsmall,cd1X,cdP,'g.','MarkerSize', 1);


 if(verbose==1)
    app.PMD.Text = 'MESHED MISSED DETECTION';
    app.PMD.Icon = 'tick.png';
    app.PSD.Icon = '';
    app.COMBINED.Icon = '';    
    close(h);
 end
            
% ///////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////
            
            %%Simulation study combining power and distortion measurements for spoofing
%%detection
%   
%   Computes the detection power for detection tests using the power and
%   distortion metrics. Follows algorithms described in
%   
%   Rothmaier, F., Chen, Y., Lo, S., & Walter, T. (2021). A Framework for 
%       GNSS Spoofing Detection through Combinations of Metrics. IEEE 
%       Transactions on Aerospace and Electronic Systems. 
%       https://doi.org/10.1109/TAES.2021.3082673
%   
%   The simulation is described and analyzed in
%   Rothmaier, F., Lo, S., Phelts, E., & Walter, T. (2021). GNSS Spoofing 
%       Detection through Metric Combinations: Calibration and Application 
%       of a general Framework. Proceedings of the 34th International 
%       Technical Meeting of the Satellite Division of the Institute of 
%       Navigation, ION GNSS+ 2021.
%   
%   Please cite the reference if using this material.
%   
%   This code and the repository it is in is provided free of charge under
%   an MIT license. For questions please contact Fabian Rothmaier
%   fabianr@stanford.edu.
%   
%   Written by Fabian Rothmaier, Stanford University, 2021


%% prepare workspace

close all;

rng(1); % for reproducability

%% set simulation parameters

CN0nom = app.NominalSignalPower.Value; % nominal signal strength in dB-Hz

sigP = app.SDforPowermetric.Value; % standard deviation of power metric in dB

ELdist = app.EarlyLateCollerator.Value; % early/late correlator positions in chips from prompt

P_FA = app.FalseAlertProbability.Value; % false alert probability

deltaCN0s = 1:app.SpooferPowerAdvantage.Value; % different spoofer power advantages in dB


offsets = 0:0.01:(1+ELdist+0.01); % offsets of spoofer's triangle in code chips

dx = 0.001;
x = -1-min(offsets):dx:1+max(offsets); % code chip range around authentic peak
xTI = round(ELdist/dx) * [-1 0 1]; % correlation tab spacing in units of x

% calculate height of nominal signal peak at each epoch
nomPeak = expectedPeaksize(CN0nom);
nomCorrFun = nomPeak .* Rcorr(x'); % nominal correlation triangle

%% run simulation during lift-off
deltaMetric = zeros(length(deltaCN0s), length(offsets));
peakSize = zeros(length(deltaCN0s), length(offsets));

[meanLLD, maxLLD, meanLLP, maxLLP] = deal(zeros(1, length(deltaCN0s)));

for deltaI = 1:length(deltaCN0s)

    
    deltaCN0 = deltaCN0s(deltaI); % spoofer power advantage

    %% set peak sizes
    CN0spo = CN0nom + deltaCN0;

    spoPeak = expectedPeaksize(CN0spo);
    
    % compute spoofed correlation function
    spoCorrFun = spoPeak .* Rcorr(x' - offsets);
    
    % sum with nominal values
    sumCorrFun = nomCorrFun  + spoCorrFun;

    %% compute nominal peak, spoofed peak
    
    peakIs = zeros(size(offsets));
    
    deltaMetric = zeros(N, length(offsets));
    measCN0 = zeros(N, length(offsets));
    
    for ii = 1:length(offsets)
    
        % find peak, assume perfect tracking
        [peakSize(deltaI, ii), peakIs(ii)] = max(sumCorrFun(:, ii));

        % run a Monte Carlo for Delta metric measurement
        corrTapStd = deltaSigmaVal(expectedCN0(peakSize(deltaI, ii)))/sqrt(2);
        corrTapNoise = corrTapStd*randn(N, length(xTI)); % is normalized by peaksize
        corrTapVals = sumCorrFun(peakIs(ii)+xTI, ii)' + peakSize(deltaI, ii)* corrTapNoise;
        
        deltaMetric(:, ii) = (corrTapVals(:, 1) - corrTapVals(:, 3)) ./ corrTapVals(:, 2);
        
        % get measured CN0 (mostly unaffected by noise on correlator taps)
        measCN0(:, ii) = expectedCN0(corrTapVals(:, 2));
        
    end
    
    % calculate log Lambda of the delta metric
    sigDelta = deltaSigmaVal(measCN0);
    logLaD = (deltaMetric ./ sigDelta).^2;
    
    % calculate log Lambda of the power metric, now do Monte Carlo for
    % noise on power metric (excluded before for comp. efficiency, does not
    % affect delta metric)
    % This includes extra noise on power measurement.
    logLaP = ((measCN0 + sigP*randn(N, length(offsets)) - CN0nom) / sigP).^2;
    
    % calculate alarms
    alarm.D = sum(logLaD > chi2inv(1-P_FA, 1), 1);
    alarm.P = sum(logLaP > chi2inv(1-P_FA, 1), 1);
    alarm.Dor = sum(logLaD > chi2inv(1-P_FA/2, 1), 1);
    alarm.Por = sum(logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.OR = sum(logLaD > chi2inv(1-P_FA/2, 1) | logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.Dand = sum(logLaD > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.Pand = sum(logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.AND = sum(logLaD > chi2inv(1-sqrt(P_FA), 1) & logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.joint = sum(logLaP + logLaD > chi2inv(1-P_FA, 2), 1);
    
    for metric = fields(alarm)'      
        % minimum P_MD at best epoch
        P_MDmin.(metric{1})(deltaI) = 1 - max(alarm.(metric{1})) / N;
        % average P_MD across all epochs
        P_MDmean.(metric{1})(deltaI) = 1 - mean(alarm.(metric{1})) / N;
    end

end


%% plot results
plt = PlotLatexStyle;

% compute detection with OR, AND, joint metric

% plot average P_MD in %
cla(app.UIAxesMESHED, 'reset')
hold(app.UIAxesMESHED,'on')
grid(app.UIAxesMESHED, "off");

p = (P_MDmean.P);
d = (P_MDmean.D);
a = (P_MDmean.AND);
o = (P_MDmean.OR);
j = (P_MDmean.joint);

pp = p(deltaCN0s);
dd = d(deltaCN0s);
aa = a(deltaCN0s);
oo = o(deltaCN0s);
jj = j(deltaCN0s);
meshedautoswicher = max(jj,max(oo,max(aa,max(pp,dd))));

x = abs(deltaCN0s);
y =  offsets*N;
[xx,yy] = meshgrid(x,y);

px = ((xx.*pp-1)/deltaI);
dx = ((xx.*dd-1)/deltaI);
ax = ((xx.*aa-1)/deltaI);
ox = ((xx.*oo-1)/deltaI);
jx = ((xx.*jj-1)/deltaI);
cx = ((xx.*meshedautoswicher-1)/deltaI);


surfc(app.UIAxesMESHED,xx,yy,px)
surfc(app.UIAxesMESHED,xx,yy,dx)
surfc(app.UIAxesMESHED,xx,yy,ax)
surfc(app.UIAxesMESHED,xx,yy,ox)
surfc(app.UIAxesMESHED,xx,yy,jx)
surfc(app.UIAxesMESHED,xx,yy,cx)


view(app.UIAxesMESHED,[35,10])
colormap (app.UIAxesMESHED,"jet(500)")
shading(app.UIAxesMESHED, "interp")
cb = colorbar(app.UIAxesMESHED);
cb.Title
cb.Title.String = "Detection Probability";

xlabel(app.UIAxesMESHED, 'Spoofer power advantage (dB)', plt.axisLabelArgs{:})
ylabel(app.UIAxesMESHED, 'Samples', plt.axisLabelArgs{:})
zlabel(app.UIAxesMESHED, 'Miss Detection Probability', plt.axisLabelArgs{:})
plt.latexLegend(app.UIAxesMESHED, 'MESHED', 'INDIVIDUALS')


%% helper functions
function ps = expectedPeaksize(CN0, N0)
%Calculate the theoretical peak size based on CN0, N0.

if nargin < 2
    N0 = -203;
end

ps = sqrt(10.^((CN0 + N0)/10))*10^12 .* 500;

end


function eCN0 = expectedCN0(peaksize, N0)
%Compute the expected CN0 as a function of correlation peak size.

if nargin < 2
    N0 = -203;
end

% compute the expected C/N0
eCN0 = 10 * log10((2*peaksize /1e15).^2) - N0;

end                              
        end

        % Value changed function: SpooferPowerAdvantage
        function SpooferPowerAdvantageValueChanged(app, event)
            app.SpooferPowerAdvantage.Value;
        end

        % Button pushed function: COMBINED
        function COMBINEDButtonPushed(app, event)
%% Top-level Monte-Carlo Script for simulating PD-ML Observables
% Author: Jason Gross with slight mods by Cagri Kilic

clc;close all;
% set some simulation defaults
s.PA = app.PAEditFieldMPELQ.Value;
s.etadB = app.EtadEditFieldMPELQ.Value;
s.N0 = app.N0EditFieldMPELQ.Value;
s.Ms = app.MsEditFieldMPELQ.Value;
s.eml = app.EmlEditFieldMPELQ.Value;
s.Ta = app.TaEditFieldMPELQ.Value;
s.WFE = app.WFEEditFieldMPELQ.Value;
s.WFEbeta = app.WFEbetaEditFieldMPELQ.Value;
s.Pbeta = app.PbetaEditFieldMPELQ.Value;
s.Tc = app.TcEditFieldMPELQ.Value;
s.sigmaP = app.sigmaPEditFieldMPELQ.Value;
s.taud = app.taudEditFieldMPELQ.Value;
s.trueDelaySigma=app.TrueDelayEditFieldMPELQ.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
% multipath estimator struct
%------------------------------------------------
s.nTaps=app.nTapsEditFieldMPELQ.Value; %select nTaps number as 5,7,15 or 41
%------------------------------------------------
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));


% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end

N = app.NoofMCRunsEpoch.Value; % number of monte carlo runs at every epoch
Np=N;% number of trials of theta
Nm=app.VectorEditFieldMPELQ.Value;  % for each theta number of measurement vectors simulated
% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution
pPriorClean=app.PRIODCleanEditFieldMPELQ.Value;
pPriorMultipath=app.PRIODMultipathEditFieldMPELQ.Value;
pPriorSpoof=app.PRIODSpoofingEditFieldMPELQ.Value;
pPriorJam=app.PRIODJammingEditFieldMPELQ.Value;

verbose=1;
if (verbose==1)
    h=waitbar(0,'Simulation Progress');
    app.COMBINED.Icon = 'loading.gif';
    app.COMBINED.Text = 'Processing...';
    
end
for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
    
end
close(h);
clear h;

if (verbose==1)
    h=waitbar(0,'Plot Progress');
    pp.ALLSIGNALSButtonMPELQ.Icon = 'loading.gif';
    app.ALLSIGNALSButtonMPELQ.Text = 'Plotting Results...';
end
% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


% clear h
% delete h
% save -v7.3 mpeIQMonteCarloall.mat
cla(app.UIAxesMESHEDsmall, 'reset')

semilogx(app.UIAxesMESHEDsmall,sp1X,spP,'r.','MarkerSize', 1);
hold(app.UIAxesMESHEDsmall,'on')
semilogx(app.UIAxesMESHEDsmall,jm1X,jmP,'b.','MarkerSize', 1);
semilogx(app.UIAxesMESHEDsmall,mp1X,mpP,'k.','MarkerSize', 1);
semilogx(app.UIAxesMESHEDsmall,cd1X,cdP,'g.','MarkerSize', 1);


 if(verbose==1)
    app.COMBINED.Text = 'COMBINED MINIMUM PMD';
    app.COMBINED.Icon = 'tick.png';
    app.PMD.Icon = '';
    app.PSD.Icon = '';    
    close(h);
 end
            
% ///////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////////
            
%%Simulation study combining power and distortion measurements for spoofing
%%detection
%   
%   Computes the detection power for detection tests using the power and
%   distortion metrics. Follows algorithms described in
%   
%   Rothmaier, F., Chen, Y., Lo, S., & Walter, T. (2021). A Framework for 
%       GNSS Spoofing Detection through Combinations of Metrics. IEEE 
%       Transactions on Aerospace and Electronic Systems. 
%       https://doi.org/10.1109/TAES.2021.3082673
%   
%   The simulation is described and analyzed in
%   Rothmaier, F., Lo, S., Phelts, E., & Walter, T. (2021). GNSS Spoofing 
%       Detection through Metric Combinations: Calibration and Application 
%       of a general Framework. Proceedings of the 34th International 
%       Technical Meeting of the Satellite Division of the Institute of 
%       Navigation, ION GNSS+ 2021.
%   
%   Please cite the reference if using this material.
%   
%   This code and the repository it is in is provided free of charge under
%   an MIT license. For questions please contact Fabian Rothmaier
%   fabianr@stanford.edu.
%   
%   Written by Fabian Rothmaier, Stanford University, 2021


%% prepare workspace

close all;

rng(1); % for reproducability

%% set simulation parameters

CN0nom = app.NominalSignalPower.Value; % nominal signal strength in dB-Hz

sigP = app.SDforPowermetric.Value; % standard deviation of power metric in dB

ELdist = app.EarlyLateCollerator.Value; % early/late correlator positions in chips from prompt

P_FA = app.FalseAlertProbability.Value; % false alert probability

deltaCN0s = 1:app.SpooferPowerAdvantage.Value; % different spoofer power advantages in dB


offsets = 0:0.01:(1+ELdist+0.01); % offsets of spoofer's triangle in code chips

dx = 0.001;
x = -1-min(offsets):dx:1+max(offsets); % code chip range around authentic peak
xTI = round(ELdist/dx) * [-1 0 1]; % correlation tab spacing in units of x

% calculate height of nominal signal peak at each epoch
nomPeak = expectedPeaksize(CN0nom);
nomCorrFun = nomPeak .* Rcorr(x'); % nominal correlation triangle

%% run simulation during lift-off
deltaMetric = zeros(length(deltaCN0s), length(offsets));
peakSize = zeros(length(deltaCN0s), length(offsets));

[meanLLD, maxLLD, meanLLP, maxLLP] = deal(zeros(1, length(deltaCN0s)));

for deltaI = 1:length(deltaCN0s)
    
    deltaCN0 = deltaCN0s(deltaI); % spoofer power advantage

    %% set peak sizes
    CN0spo = CN0nom + deltaCN0;

    spoPeak = expectedPeaksize(CN0spo);
    
    % compute spoofed correlation function
    spoCorrFun = spoPeak .* Rcorr(x' - offsets);
    
    % sum with nominal values
    sumCorrFun = nomCorrFun  + spoCorrFun;

    %% compute nominal peak, spoofed peak
    
    peakIs = zeros(size(offsets));
    
    deltaMetric = zeros(N, length(offsets));
    measCN0 = zeros(N, length(offsets));
    
    for ii = 1:length(offsets)
    
        % find peak, assume perfect tracking
        [peakSize(deltaI, ii), peakIs(ii)] = max(sumCorrFun(:, ii));

        % run a Monte Carlo for Delta metric measurement
        corrTapStd = deltaSigmaVal(expectedCN0(peakSize(deltaI, ii)))/sqrt(2);
        corrTapNoise = corrTapStd*randn(N, length(xTI)); % is normalized by peaksize
        corrTapVals = sumCorrFun(peakIs(ii)+xTI, ii)' + peakSize(deltaI, ii)* corrTapNoise;
        
        deltaMetric(:, ii) = (corrTapVals(:, 1) - corrTapVals(:, 3)) ./ corrTapVals(:, 2);
        
        % get measured CN0 (mostly unaffected by noise on correlator taps)
        measCN0(:, ii) = expectedCN0(corrTapVals(:, 2));
        
    end
    
    % calculate log Lambda of the delta metric
    sigDelta = deltaSigmaVal(measCN0);
    logLaD = (deltaMetric ./ sigDelta).^2;
    
    % calculate log Lambda of the power metric, now do Monte Carlo for
    % noise on power metric (excluded before for comp. efficiency, does not
    % affect delta metric)
    % This includes extra noise on power measurement.
    logLaP = ((measCN0 + sigP*randn(N, length(offsets)) - CN0nom) / sigP).^2;
    
    % calculate alarms
    alarm.D = sum(logLaD > chi2inv(1-P_FA, 1), 1);
    alarm.P = sum(logLaP > chi2inv(1-P_FA, 1), 1);
    alarm.Dor = sum(logLaD > chi2inv(1-P_FA/2, 1), 1);
    alarm.Por = sum(logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.OR = sum(logLaD > chi2inv(1-P_FA/2, 1) | logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.Dand = sum(logLaD > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.Pand = sum(logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.AND = sum(logLaD > chi2inv(1-sqrt(P_FA), 1) & logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.joint = sum(logLaP + logLaD > chi2inv(1-P_FA, 2), 1);
    
    for metric = fields(alarm)'      
        % minimum P_MD at best epoch
        P_MDmin.(metric{1})(deltaI) = 1 - max(alarm.(metric{1})) / N;
        % average P_MD across all epochs
        P_MDmean.(metric{1})(deltaI) = 1 - mean(alarm.(metric{1})) / N;
    end

end


%% plot results
plt = PlotLatexStyle;

% compute detection with OR, AND, joint metric

% plot average P_MD in %
cla(app.UIAxesMESHED, 'reset')
hold(app.UIAxesMESHED,'on')
grid(app.UIAxesMESHED, "off");

p = P_MDmean.P*100;
d = P_MDmean.D*100;
a = P_MDmean.AND*100;
o = P_MDmean.OR*100;
j = P_MDmean.joint*100;

pp = p(deltaCN0s);
dd = d(deltaCN0s);
aa = a(deltaCN0s);
oo = o(deltaCN0s);
jj = j(deltaCN0s);

plot(app.UIAxesMESHED, pp, '-+', 'LineWidth', 1.2)
plot(app.UIAxesMESHED, dd, 'LineWidth', 1.2, "Color", 'c')
plot(app.UIAxesMESHED, aa, '--', 'LineWidth', 1.2)
plot(app.UIAxesMESHED, oo, '-.', 'LineWidth', 1.2)
plot(app.UIAxesMESHED, jj, '-d', 'LineWidth', 1.2)
yline(app.UIAxesMESHED, 10, '-', 'Maximum Missed Detection(10%)', 'LineWidth', 2, "Color", 'r')

plot(app.UIAxesMESHED, min(jj,min(oo,min(aa,min(pp,dd)))), '-d', 'LineWidth', 3, "Color", 'm')


plt.latexLegend(app.UIAxesMESHED, 'P', 'D', 'AND', 'OR', 'joint', 'Threshold', 'AUTO-SWITCHER')

xlabel(app.UIAxesMESHED, 'Spoofer power advantage in (dB)', plt.axisLabelArgs{:})
ylabel(app.UIAxesMESHED, 'Average $P_{MD}$ during lift-off in $\%$ ', plt.axisLabelArgs{:})


%% helper functions
function ps = expectedPeaksize(CN0, N0)
%Calculate the theoretical peak size based on CN0, N0.

if nargin < 2
    N0 = -203;
end

ps = sqrt(10.^((CN0 + N0)/10))*10^12 .* 500;

end


function eCN0 = expectedCN0(peaksize, N0)
%Compute the expected CN0 as a function of correlation peak size.

if nargin < 2
    N0 = -203;
end

% compute the expected C/N0
eCN0 = 10 * log10((2*peaksize /1e15).^2) - N0;

end                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PDDEFENSESOFTWARENUAAUIFigure = uifigure('Visible', 'off');
            app.PDDEFENSESOFTWARENUAAUIFigure.Name = 'PD DEFENSE SOFTWARE - NUAA';
            app.TabGroup = uitabgroup(app.PDDEFENSESOFTWARENUAAUIFigure);
            app.TabGroup.Tooltip = {''};
            app.AUTOSWITCHERTab = uitab(app.TabGroup);
            app.AUTOSWITCHERTab.Title = 'AUTO-SWITCHER';
            app.UIAxesMESHED = uiaxes(app.AUTOSWITCHERTab);
            app.UIAxesMESHED.XTick = [];
            app.UIAxesMESHED.YTick = [];
            app.Panel_12 = uipanel(app.AUTOSWITCHERTab);
            app.UIAxesMESHEDsmall = uiaxes(app.Panel_12);
            app.UIAxesMESHEDsmall.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesMESHEDsmall.XTickLabel = '';
            app.UIAxesMESHEDsmall.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesMESHEDsmall.YTickLabel = '';
            app.Panel_14 = uipanel(app.Panel_12);
            app.FalseAlertProbability = uieditfield(app.Panel_14, 'numeric');
            app.FalseAlertProbability.ValueChangedFcn = createCallbackFcn(app, @FalseAlertProbabilityValueChanged, true);
            app.FalseAlertProbability.Value = 1e-08;
            app.FalseAlertProbabilityLabel = uilabel(app.Panel_14);
            app.FalseAlertProbabilityLabel.Text = 'False Alert Probability';
            app.NoofMCRunsEpoch = uieditfield(app.Panel_14, 'numeric');
            app.NoofMCRunsEpoch.ValueChangedFcn = createCallbackFcn(app, @NoofMCRunsEpochValueChanged, true);
            app.NoofMCRunsEpoch.Value = 1000;
            app.NoofMCRunsEpochLabel = uilabel(app.Panel_14);
            app.NoofMCRunsEpochLabel.Text = 'No of MC Runs/Epoch';
            app.NominalSignalPower = uieditfield(app.Panel_14, 'numeric');
            app.NominalSignalPower.ValueChangedFcn = createCallbackFcn(app, @NominalSignalPowerValueChanged, true);
            app.NominalSignalPower.Value = 40;
            app.NominalSignalPowerLabel = uilabel(app.Panel_14);
            app.NominalSignalPowerLabel.Text = 'Nominal Signal Power';
            app.SDforPowermetric = uieditfield(app.Panel_14, 'numeric');
            app.SDforPowermetric.ValueChangedFcn = createCallbackFcn(app, @SDforPowermetricValueChanged, true);
            app.SDforPowermetric.Value = 2;
            app.SDforPowermetricLabel = uilabel(app.Panel_14);
            app.SDforPowermetricLabel.Text = 'SD for Power metric';
            app.EarlyLateCollerator = uieditfield(app.Panel_14, 'numeric');
            app.EarlyLateCollerator.ValueChangedFcn = createCallbackFcn(app, @EarlyLateColleratorValueChanged, true);
            app.EarlyLateCollerator.Value = 0.052;
            app.EarlyLateColleratorLabel_5 = uilabel(app.Panel_14);
            app.EarlyLateColleratorLabel_5.Text = 'Early / Late Collerator';
            app.SpooferPowerAdvantage = uieditfield(app.Panel_14, 'numeric');
            app.SpooferPowerAdvantage.ValueChangedFcn = createCallbackFcn(app, @SpooferPowerAdvantageValueChanged, true);
            app.SpooferPowerAdvantage.Value = 20;
            app.SpooferPowerAdvantLabel = uilabel(app.Panel_14);
            app.SpooferPowerAdvantLabel.Text = 'Spoofer Power Advant.';
            app.Panel_13 = uipanel(app.AUTOSWITCHERTab);
            app.PSD = uibutton(app.Panel_13, 'push');
            app.PSD.ButtonPushedFcn = createCallbackFcn(app, @PSDPushed, true);
            app.PSD.Text = 'SPOOF DETECTION - MESHED SWICHER';
            app.PMD = uibutton(app.Panel_13, 'push');
            app.PMD.ButtonPushedFcn = createCallbackFcn(app, @PMDButtonPushed, true);
            app.PMD.Text = 'MISSED DETECTION - MESHED SWICHER';
            app.COMBINED = uibutton(app.Panel_13, 'push');
            app.COMBINED.ButtonPushedFcn = createCallbackFcn(app, @COMBINEDButtonPushed, true);
            app.COMBINED.Text = 'COMBINED MINIMUM PMD';
            app.GenerateMCDataButton_2 = uibutton(app.AUTOSWITCHERTab, 'push');
            app.GenerateMCDataButton_2.Icon = 'data.png';
            app.GenerateMCDataButton_2.Text = 'Generate MC Data';
            app.GeneratePMCDataButton_2 = uibutton(app.AUTOSWITCHERTab, 'push');
            app.GeneratePMCDataButton_2.Icon = 'Files.png';
            app.GeneratePMCDataButton_2.Text = 'Generate PMC Data';
            app.PERFOMANCEANALYSISFORPDAUTOSWITCHERSPOOFINGDETECTIONLabel = uilabel(app.AUTOSWITCHERTab);
            app.PERFOMANCEANALYSISFORPDAUTOSWITCHERSPOOFINGDETECTIONLabel.Text = 'PERFOMANCE ANALYSIS FOR P-D AUTO-SWITCHER SPOOFING DETECTION';
            app.INITIALLabel_2 = uilabel(app.AUTOSWITCHERTab);
            app.INITIALLabel_2.Text = 'INITIAL';
            app.PRIODCleanEditField_2 = uieditfield(app.AUTOSWITCHERTab, 'numeric');
            app.PRIODCleanEditField_2.Value = 0.6;
            app.PRIODMultipathEditField_2 = uieditfield(app.AUTOSWITCHERTab, 'numeric');
            app.PRIODMultipathEditField_2.Value = 0.2;
            app.PRIODSpoofingEditField_2 = uieditfield(app.AUTOSWITCHERTab, 'numeric');
            app.PRIODSpoofingEditField_2.Value = 0.05;
            app.PRIODJammingEditField_2 = uieditfield(app.AUTOSWITCHERTab, 'numeric');
            app.PRIODJammingEditField_2.Value = 0.15;
            app.ClearButtonGLRT = uibutton(app.AUTOSWITCHERTab, 'push');
            app.ClearButtonGLRT.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonGLRTPushed, true);
            app.ClearButtonGLRT.Icon = 'Clear.png';
            app.ClearButtonGLRT.Text = 'Clear';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '钳形分布模型';
            app.UIAxes = uiaxes(app.Tab);
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ALLSIGNALSButtonPushed, true);
            app.Panel_6 = uipanel(app.Tab);
            app.Panel_4 = uipanel(app.Panel_6);
            app.TrialsEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.TrialsEditFieldPDMODEL.ValueDisplayFormat = '%.0f';
            app.TrialsEditFieldPDMODEL.ValueChangedFcn = createCallbackFcn(app, @TrialsEditFieldValuePDMODELChanged, true);
            app.TrialsEditFieldPDMODEL.Value = 1000;
            app.SigmaPEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.SigmaPEditFieldPDMODEL.ValueDisplayFormat = '%.2f';
            app.SigmaPEditFieldPDMODEL.ValueChangedFcn = createCallbackFcn(app, @SigmaPEditFieldValuePDMODELChanged, true);
            app.SigmaPEditFieldPDMODEL.Value = 0.4;
            app.SigmaPEditFieldLabel = uilabel(app.Panel_4);
            app.SigmaPEditFieldLabel.Text = 'SigmaP';
            app.WFEbetaEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.WFEbetaEditFieldPDMODEL.ValueChangedFcn = createCallbackFcn(app, @WFEbetaEditFieldValuePDMODELChanged, true);
            app.WFEbetaEditFieldPDMODEL.Value = 20000000;
            app.WFEbetaEditFieldLabel = uilabel(app.Panel_4);
            app.WFEbetaEditFieldLabel.Text = 'WFEbeta';
            app.PbetaEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.PbetaEditFieldPDMODEL.ValueDisplayFormat = '%.0f';
            app.PbetaEditFieldPDMODEL.ValueChangedFcn = createCallbackFcn(app, @PbetaPDMODELEditFieldValueChanged, true);
            app.PbetaEditFieldPDMODEL.Value = -130.8815;
            app.PbetaEditFieldLabel = uilabel(app.Panel_4);
            app.PbetaEditFieldLabel.Text = 'Pbeta';
            app.TrialsEditFieldLabelPDMODEL = uilabel(app.Panel_4);
            app.TrialsEditFieldLabelPDMODEL.Text = '试验次数';
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = '向量值';
            app.VectorsEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.VectorsEditFieldPDMODEL.ValueDisplayFormat = '%.0f';
            app.VectorsEditFieldPDMODEL.ValueChangedFcn = createCallbackFcn(app, @VectorsEditFieldValuePDMODELChanged, true);
            app.VectorsEditFieldPDMODEL.Value = 20;
            app.PAEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.PAEditFieldPDMODEL.ValueDisplayFormat = '%.0f';
            app.PAEditFieldPDMODEL.ValueChangedFcn = createCallbackFcn(app, @PAEditFieldValuePDMODELChanged, true);
            app.PAEditFieldPDMODEL.Value = -156;
            app.PAEditFieldLabel = uilabel(app.Panel_4);
            app.PAEditFieldLabel.Text = 'PA';
            app.N0EditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.N0EditFieldPDMODEL.ValueDisplayFormat = '%.0f';
            app.N0EditFieldPDMODEL.Value = -204;
            app.N0EditFieldLabelPDMODEL = uilabel(app.Panel_4);
            app.N0EditFieldLabelPDMODEL.Text = 'N0';
            app.MsEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.MsEditFieldPDMODEL.ValueDisplayFormat = '%.0f';
            app.MsEditFieldPDMODEL.Value = 7;
            app.MsEditFieldLabelPDMODEL = uilabel(app.Panel_4);
            app.MsEditFieldLabelPDMODEL.Text = 'Ms';
            app.TaudEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.TaudEditFieldPDMODEL.ValueDisplayFormat = '%.2f';
            app.TaudEditFieldPDMODEL.Value = 0.15;
            app.TaudEditFieldLabelPDMODEL = uilabel(app.Panel_4);
            app.TaudEditFieldLabelPDMODEL.Text = 'Taud';
            app.TaEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.TaEditFieldPDMODEL.ValueDisplayFormat = '%.2f';
            app.TaEditFieldPDMODEL.Value = 0.1;
            app.TaEditFieldLabelPDMODEL = uilabel(app.Panel_4);
            app.TaEditFieldLabelPDMODEL.Text = 'Ta';
            app.WFEEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.WFEEditFieldPDMODEL.Value = 2000000;
            app.WFEEditFieldLabelPDMODEL = uilabel(app.Panel_4);
            app.WFEEditFieldLabelPDMODEL.Text = 'WFE';
            app.TcEditFieldPDMODEL = uieditfield(app.Panel_4, 'numeric');
            app.TcEditFieldPDMODEL.Value = 1e-06;
            app.TcEditFieldLabelPDMODEL = uilabel(app.Panel_4);
            app.TcEditFieldLabelPDMODEL.Text = 'Tc';
            app.Panel_3 = uipanel(app.Tab);
            app.Button_3 = uibutton(app.Panel_3, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '无干扰';
            app.Button_4 = uibutton(app.Panel_3, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '多路径';
            app.Button_5 = uibutton(app.Panel_3, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '欺骗';
            app.Button_6 = uibutton(app.Panel_3, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '干扰';
            app.Button_2 = uibutton(app.Panel_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ALLSIGNALSButtonPushed, true);
            app.Button_2.Text = '所有信号';
            app.MCButton = uibutton(app.Tab, 'push');
            app.MCButton.ButtonPushedFcn = createCallbackFcn(app, @MCButtonPushed, true);
            app.MCButton.Icon = 'data.png';
            app.MCButton.Text = '生成MC数据';
            app.PMCButton = uibutton(app.Tab, 'push');
            app.PMCButton.ButtonPushedFcn = createCallbackFcn(app, @PMCButtonPushed, true);
            app.PMCButton.Icon = 'Files.png';
            app.PMCButton.Text = '生成PMC数据';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '蒙特卡罗模拟的钳形观测量结果';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '初始值';
            app.PRIODCleanEditField = uieditfield(app.Tab, 'numeric');
            app.PRIODCleanEditField.ValueChangedFcn = createCallbackFcn(app, @PRIODCleanEditFieldValueChanged, true);
            app.PRIODCleanEditField.Value = 0.6;
            app.PRIODMultipathEditField = uieditfield(app.Tab, 'numeric');
            app.PRIODMultipathEditField.ValueChangedFcn = createCallbackFcn(app, @PRIODMultipathEditFieldValueChanged, true);
            app.PRIODMultipathEditField.Value = 0.2;
            app.PRIODSpoofingEditField = uieditfield(app.Tab, 'numeric');
            app.PRIODSpoofingEditField.ValueChangedFcn = createCallbackFcn(app, @PRIODSpoofingEditFieldValueChanged, true);
            app.PRIODSpoofingEditField.Value = 0.05;
            app.PRIODJammingEditField = uieditfield(app.Tab, 'numeric');
            app.PRIODJammingEditField.ValueChangedFcn = createCallbackFcn(app, @PRIODJammingEditFieldValueChanged, true);
            app.PRIODJammingEditField.Value = 0.15;
            app.Button_7 = uibutton(app.Tab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'Clear.png';
            app.Button_7.Text = '清除';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '顶层钳形模型';
            app.UIAxes_2 = uiaxes(app.Tab_2);
            app.UIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.Panel_11 = uipanel(app.Tab_2);
            app.Panel = uipanel(app.Tab_2);
            app.DButton = uibutton(app.Panel, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D值无干扰/干扰的理论分布';
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '多余能量和失真度的离散';
            app.DButton_2 = uibutton(app.Panel, 'push');
            app.DButton_2.ButtonPushedFcn = createCallbackFcn(app, @DButton_2Pushed, true);
            app.DButton_2.Text = '一个D值的多余能量和失真度';
            app.PP0Button = uibutton(app.Panel, 'push');
            app.PP0Button.ButtonPushedFcn = createCallbackFcn(app, @PP0ButtonPushed, true);
            app.PP0Button.Text = '一个P-P0的失真度和多余能量';
            app.Button_9 = uibutton(app.Panel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed2, true);
            app.Button_9.Icon = 'Clear.png';
            app.Button_9.Text = '清除';
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = '顶层钳形模型的模拟';
            app.Panel_10 = uipanel(app.Tab_2);
            app.Panel_5 = uipanel(app.Panel_10);
            app.PAEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.PAEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @PAEditFieldTOPPINCERValueChanged, true);
            app.PAEditFieldTOPPINCER.Value = -156;
            app.PAEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.PAEditFieldLabelTOPPINCER.Text = 'PA';
            app.N0EditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.N0EditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @N0EditFieldTOPPINCERValueChanged, true);
            app.N0EditFieldTOPPINCER.Value = -204;
            app.N0EditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.N0EditFieldLabelTOPPINCER.Text = 'N0';
            app.MsEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.MsEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @MsEditFieldTOPPINCERValueChanged, true);
            app.MsEditFieldTOPPINCER.Value = 7;
            app.MsEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.MsEditFieldLabelTOPPINCER.Text = 'Ms';
            app.taudEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.taudEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @taudEditFieldTOPPINCERValueChanged, true);
            app.taudEditFieldTOPPINCER.Value = 0.15;
            app.taudEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.taudEditFieldLabelTOPPINCER.Text = 'Taud';
            app.TaEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.TaEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @TaEditFieldTOPPINCERValueChanged, true);
            app.TaEditFieldTOPPINCER.Value = 0.1;
            app.TaEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.TaEditFieldLabelTOPPINCER.Text = 'Ta';
            app.WFEEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.WFEEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @WFEEditFieldTOPPINCERValueChanged, true);
            app.WFEEditFieldTOPPINCER.Value = 2000000;
            app.WFEEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.WFEEditFieldLabelTOPPINCER.Text = 'WFE';
            app.WFEbetaEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.WFEbetaEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @WFEbetaEditFieldTOPPINCERValueChanged, true);
            app.WFEbetaEditFieldTOPPINCER.Value = 20000000;
            app.WFEbetaEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.WFEbetaEditFieldLabelTOPPINCER.Text = 'WFEbeta';
            app.PbetaEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.PbetaEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @PbetaEditFieldTOPPINCERValueChanged, true);
            app.PbetaEditFieldTOPPINCER.Value = -130.8815;
            app.PbetaEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.PbetaEditFieldLabelTOPPINCER.Text = 'Pbeta';
            app.TcEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.TcEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @TcEditFieldTOPPINCERValueChanged, true);
            app.TcEditFieldTOPPINCER.Value = 1e-06;
            app.TcEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.TcEditFieldLabelTOPPINCER.Text = 'Tc';
            app.SigmaPEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.SigmaPEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @SigmaPEditFieldTOPPINCERValueChanged, true);
            app.SigmaPEditFieldTOPPINCER.Value = 0.2;
            app.SigmaPEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.SigmaPEditFieldLabelTOPPINCER.Text = 'sigmaP';
            app.SamplesEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.SamplesEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @SamplesEditFieldTOPPINCERValueChanged, true);
            app.SamplesEditFieldTOPPINCER.Value = 1000;
            app.SamplesEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.SamplesEditFieldLabelTOPPINCER.Text = '样本量';
            app.POEditFieldTOPPINCER = uieditfield(app.Panel_5, 'numeric');
            app.POEditFieldTOPPINCER.ValueChangedFcn = createCallbackFcn(app, @POEditFieldTOPPINCERValueChanged, true);
            app.POEditFieldTOPPINCER.Value = -140.0906;
            app.POEditFieldLabelTOPPINCER = uilabel(app.Panel_5);
            app.POEditFieldLabelTOPPINCER.Text = 'PO';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '钳形模型的评估';
            app.UIAxesBRB2 = uiaxes(app.Tab_3);
            app.UIAxesBRB2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesBRB2.XTickLabel = '';
            app.UIAxesBRB2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesBRB2.YTickLabel = '';
            app.UIAxesBRB1 = uiaxes(app.Tab_3);
            app.UIAxesBRB1.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesBRB1.XTickLabel = '';
            app.UIAxesBRB1.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesBRB1.YTickLabel = '';
            app.Panel_15 = uipanel(app.Tab_3);
            app.Panel_15.Title = '决策';
            app.USEPMCDATABRBButton = uibutton(app.Panel_15, 'push');
            app.USEPMCDATABRBButton.ButtonPushedFcn = createCallbackFcn(app, @USEPMCDATABRB_ButtonPushed, true);
            app.USEPMCDATABRBButton.Text = '应用PMC数据';
            app.INITIAL = uibutton(app.Panel_15, 'push');
            app.INITIAL.ButtonPushedFcn = createCallbackFcn(app, @TEST, true);
            app.INITIAL.Text = '决策区域的初始分区';
            app.FIGURES = uibutton(app.Panel_15, 'push');
            app.FIGURES.ButtonPushedFcn = createCallbackFcn(app, @FIGURESButtonPushed, true);
            app.FIGURES.Text = '图表';
            app.Label_5 = uilabel(app.Tab_3);
            app.Label_5.Text = '创建评估网格和钳形模型';
            app.PDMLTab = uitab(app.TabGroup);
            app.PDMLTab.Title = 'PD-ML检测器的模型';
            app.UIAxesMPELQ = uiaxes(app.PDMLTab);
            app.UIAxesMPELQ.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesMPELQ.XTickLabel = '';
            app.UIAxesMPELQ.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesMPELQ.YTickLabel = '';
            app.Panel_7 = uipanel(app.PDMLTab);
            app.Panel_9 = uipanel(app.Panel_7);
            app.PAEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.PAEditFieldMPELQ.ValueDisplayFormat = '%.0f';
            app.PAEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @PAEditFieldMPELQValueChanged, true);
            app.PAEditFieldMPELQ.Value = -156;
            app.sPAEditFieldLabeMPELQ = uilabel(app.Panel_9);
            app.sPAEditFieldLabeMPELQ.Text = 'PA';
            app.EtadEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.EtadEditFieldMPELQ.ValueDisplayFormat = '%.0f';
            app.EtadEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @EtadEditFieldMPELQValueChanged, true);
            app.EtadEditFieldMPELQ.Value = 20;
            app.sN0EditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.sN0EditFieldLabelMPELQ.Text = 'EtadB';
            app.EmlEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.EmlEditFieldMPELQ.ValueDisplayFormat = '%.2f';
            app.EmlEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @EmlEditFieldMPELQValueChanged, true);
            app.EmlEditFieldMPELQ.Value = 0.3;
            app.sMsEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.sMsEditFieldLabelMPELQ.Text = 'Eml';
            app.taudEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.taudEditFieldMPELQ.ValueDisplayFormat = '%.2f';
            app.taudEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @taudEditFieldMPELQValueChanged, true);
            app.taudEditFieldMPELQ.Value = 0.15;
            app.staudEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.staudEditFieldLabelMPELQ.Text = 'Taud';
            app.TaEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.TaEditFieldMPELQ.ValueDisplayFormat = '%.2f';
            app.TaEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @TaEditFieldMPELQValueChanged, true);
            app.TaEditFieldMPELQ.Value = 0.1;
            app.sTaEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.sTaEditFieldLabelMPELQ.Text = 'Ta';
            app.WFEEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.WFEEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @WFEEditFieldMPELQValueChanged, true);
            app.WFEEditFieldMPELQ.Value = 2000000;
            app.sWFEEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.sWFEEditFieldLabelMPELQ.Text = 'WFE';
            app.WFEbetaEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.WFEbetaEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @WFEbetaEditFieldMPELQValueChanged, true);
            app.WFEbetaEditFieldMPELQ.Value = 20000000;
            app.sWFEbetaEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.sWFEbetaEditFieldLabelMPELQ.Text = 'WFEbeta';
            app.PbetaEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.PbetaEditFieldMPELQ.ValueDisplayFormat = '%.4f';
            app.PbetaEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @PbetaEditFieldMPELQValueChanged, true);
            app.PbetaEditFieldMPELQ.Value = -130.8815;
            app.sPbetaEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.sPbetaEditFieldLabelMPELQ.Text = 'Pbeta';
            app.TcEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.TcEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @TcEditFieldMPELQValueChanged, true);
            app.TcEditFieldMPELQ.Value = 1e-06;
            app.sTcEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.sTcEditFieldLabelMPELQ.Text = 'Tc';
            app.sigmaPEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.sigmaPEditFieldMPELQ.ValueDisplayFormat = '%.2f';
            app.sigmaPEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @sigmaPEditFieldMPELQValueChanged, true);
            app.sigmaPEditFieldMPELQ.Value = 0.4;
            app.ssigmaPEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.ssigmaPEditFieldLabelMPELQ.Text = 'sigmaP';
            app.VectorsEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.VectorsEditFieldLabelMPELQ.Text = 'TrueDelayS';
            app.TrueDelayEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.TrueDelayEditFieldMPELQ.ValueDisplayFormat = '%.2f';
            app.TrueDelayEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @TrueDelayEditFieldMPELQValueChanged, true);
            app.TrueDelayEditFieldMPELQ.Value = 0.03;
            app.msEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.msEditFieldLabelMPELQ.Text = 'Ms';
            app.MsEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.MsEditFieldMPELQ.ValueDisplayFormat = '%.0f';
            app.MsEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @MsEditFieldMPELQValueChanged, true);
            app.MsEditFieldMPELQ.Value = 7;
            app.NoEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.NoEditFieldLabelMPELQ.Text = 'N0';
            app.N0EditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.N0EditFieldMPELQ.ValueDisplayFormat = '%.0f';
            app.N0EditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @N0EditFieldMPELQValueChanged, true);
            app.N0EditFieldMPELQ.Value = -204;
            app.TrialsEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.TrialsEditFieldLabelMPELQ.Text = '试验次数';
            app.VectorEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.VectorEditFieldMPELQ.ValueDisplayFormat = '%.0f';
            app.VectorEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @VectorEditFieldMPELQValueChanged, true);
            app.VectorEditFieldMPELQ.Value = 20;
            app.TrialsEditFieldMPELQ = uieditfield(app.Panel_9, 'numeric');
            app.TrialsEditFieldMPELQ.ValueDisplayFormat = '%.0f';
            app.TrialsEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @TrialsEditFieldMPELQValueChanged, true);
            app.TrialsEditFieldMPELQ.Value = 1000;
            app.VectorEditFieldLabelMPELQ = uilabel(app.Panel_9);
            app.VectorEditFieldLabelMPELQ.Text = '向量值';
            app.Panel_8 = uipanel(app.PDMLTab);
            app.CLEANButtonMPELQ = uibutton(app.Panel_8, 'push');
            app.CLEANButtonMPELQ.ButtonPushedFcn = createCallbackFcn(app, @CLEANButtonMPELQPushed, true);
            app.CLEANButtonMPELQ.Text = '无干扰';
            app.MULTIPATHButtonMPELQ = uibutton(app.Panel_8, 'push');
            app.MULTIPATHButtonMPELQ.ButtonPushedFcn = createCallbackFcn(app, @MULTIPATHButtonMPELQPushed, true);
            app.MULTIPATHButtonMPELQ.Text = '多路径';
            app.SPOOFINGButtonMPELQ = uibutton(app.Panel_8, 'push');
            app.SPOOFINGButtonMPELQ.ButtonPushedFcn = createCallbackFcn(app, @SPOOFINGButtonMPELQPushed, true);
            app.SPOOFINGButtonMPELQ.Text = '欺骗';
            app.JAMMINGButtonMPELQ = uibutton(app.Panel_8, 'push');
            app.JAMMINGButtonMPELQ.ButtonPushedFcn = createCallbackFcn(app, @JAMMINGButtonMPELQPushed, true);
            app.JAMMINGButtonMPELQ.Text = '干扰';
            app.ALLSIGNALSButtonMPELQ = uibutton(app.Panel_8, 'push');
            app.ALLSIGNALSButtonMPELQ.ButtonPushedFcn = createCallbackFcn(app, @ALLSIGNALSButtonMPELQPushed, true);
            app.ALLSIGNALSButtonMPELQ.Text = '所有信号';
            app.MPELQButton = uibutton(app.PDMLTab, 'push');
            app.MPELQButton.ButtonPushedFcn = createCallbackFcn(app, @MPELQButtonPushed, true);
            app.MPELQButton.Icon = 'data.png';
            app.MPELQButton.Text = '生成MPELQ数据';
            app.PDMLLabel = uilabel(app.PDMLTab);
            app.PDMLLabel.Text = 'PD-ML检测器的模拟——基于顶层蒙特卡罗模拟的单通道模型';
            app.DLabel = uilabel(app.PDMLTab);
            app.DLabel.Text = '初始D值';
            app.PRIODCleanEditFieldMPELQ = uieditfield(app.PDMLTab, 'numeric');
            app.PRIODCleanEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @PRIODCleanEditFieldMPELQValueChanged, true);
            app.PRIODCleanEditFieldMPELQ.Value = 0.6;
            app.PRIODMultipathEditFieldMPELQ = uieditfield(app.PDMLTab, 'numeric');
            app.PRIODMultipathEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @PRIODMultipathEditFieldMPELQValueChanged, true);
            app.PRIODMultipathEditFieldMPELQ.Value = 0.2;
            app.PRIODSpoofingEditFieldMPELQ = uieditfield(app.PDMLTab, 'numeric');
            app.PRIODSpoofingEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @PRIODSpoofingEditFieldMPELQValueChanged, true);
            app.PRIODSpoofingEditFieldMPELQ.Value = 0.05;
            app.PRIODJammingEditFieldMPELQ = uieditfield(app.PDMLTab, 'numeric');
            app.PRIODJammingEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @PRIODJammingEditFieldMPELQValueChanged, true);
            app.PRIODJammingEditFieldMPELQ.Value = 0.15;
            app.ClearButtonMPELQ = uibutton(app.PDMLTab, 'push');
            app.ClearButtonMPELQ.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonMPELQPushed, true);
            app.ClearButtonMPELQ.Icon = 'Clear.png';
            app.ClearButtonMPELQ.Text = '清除';
            app.nTapsEditFieldMPELQ = uieditfield(app.PDMLTab, 'numeric');
            app.nTapsEditFieldMPELQ.ValueDisplayFormat = '%.0f';
            app.nTapsEditFieldMPELQ.ValueChangedFcn = createCallbackFcn(app, @nTapsEditFieldMPELQValueChanged, true);
            app.nTapsEditFieldMPELQ.Value = 11;
            app.TrialsEditFieldLabel_6 = uilabel(app.PDMLTab);
            app.TrialsEditFieldLabel_6.Text = '抽头数';
            app.PDMLTab_2 = uitab(app.TabGroup);
            app.PDMLTab_2.Title = 'PD-ML检测器';
            app.UIAxesPDML_2 = uiaxes(app.PDMLTab_2);
            app.UIAxesPDML_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesPDML_2.XTickLabel = '';
            app.UIAxesPDML_2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesPDML_2.YTickLabel = '';
            app.UIAxesPDML_1 = uiaxes(app.PDMLTab_2);
            app.UIAxesPDML_1.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesPDML_1.XTickLabel = '';
            app.UIAxesPDML_1.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesPDML_1.YTickLabel = '';
            app.DECISIONPanel_PDML = uipanel(app.PDMLTab_2);
            app.DECISIONPanel_PDML.Title = 'DECISION';
            app.USEPMCDATABRBButton_PDML = uibutton(app.DECISIONPanel_PDML, 'push');
            app.USEPMCDATABRBButton_PDML.Text = '应用PMC数据';
            app.INITIAL_PDML = uibutton(app.DECISIONPanel_PDML, 'push');
            app.INITIAL_PDML.ButtonPushedFcn = createCallbackFcn(app, @INITIAL_PDMLButtonPushed, true);
            app.INITIAL_PDML.Text = 'INITIAL';
            app.FIGURES_PDML = uibutton(app.DECISIONPanel_PDML, 'push');
            app.FIGURES_PDML.ButtonPushedFcn = createCallbackFcn(app, @FIGURES_PDMLButtonPushed, true);
            app.FIGURES_PDML.Text = 'FIGURES';
            app.PDMLLabel_2 = uilabel(app.PDMLTab_2);
            app.PDMLLabel_2.Text = 'PD-ML检测器的决策区域';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '软件介绍';
            app.TextArea = uitextarea(app.Tab_4);
            app.TextArea.Placeholder = 'RESULTS';
            app.TextArea.Value = {'如何使用这个软件'};
            app.Button = uibutton(app.Tab_4, 'push');
            app.Button.Text = '?';
            app.Image2 = uiimage(app.Tab_4);
            app.Image2.ImageSource = 'nuaa.png';
            app.CHINAFLAG = uiimage(app.PDDEFENSESOFTWARENUAAUIFigure);
            app.CHINAFLAG.ImageSource = 'Chinese.png';
            app.USFLAG = uiimage(app.PDDEFENSESOFTWARENUAAUIFigure);
            app.USFLAG.ImageClickedFcn = createCallbackFcn(app, @USFLAGImageClicked, true);
            app.USFLAG.ImageSource = 'English.jpg';
            app.Label_6 = uilabel(app.PDDEFENSESOFTWARENUAAUIFigure);
            app.Label_6.Text = '模拟进度';
            app.Switch = uiswitch(app.PDDEFENSESOFTWARENUAAUIFigure, 'slider');
            app.Switch.Items = {'ENGLISH', '中文'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '中文';
            app.PercentSign = uieditfield(app.PDDEFENSESOFTWARENUAAUIFigure, 'text');
            app.PercentSign.Editable = 'off';
            app.PercentSign.Tooltip = {''};
            app.PercentSign.Value = '%';
            app.PercentValue = uieditfield(app.PDDEFENSESOFTWARENUAAUIFigure, 'numeric');
            app.PercentValue.ValueDisplayFormat = '%.0f';
            app.PercentValue.ValueChangedFcn = createCallbackFcn(app, @PercentValueValueChanged, true);
            app.PercentValue.Editable = 'off';
            app.Image = uiimage(app.PDDEFENSESOFTWARENUAAUIFigure);
            app.Image.ImageSource = 'heading.png';
            % Show the figure after all components are created
            app.PDDEFENSESOFTWARENUAAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PD_DEFENSE_SOFTWARE_CHINESE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PDDEFENSESOFTWARENUAAUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PDDEFENSESOFTWARENUAAUIFigure)
        end
    end
end